classdef OnlineOptimizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        CollectNaturalStimCheckBox     matlab.ui.control.CheckBox
        OptimizeButton                 matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        StimulateandFetchButton        matlab.ui.control.Button
        ApplyFilterCheckBox            matlab.ui.control.CheckBox
        StreamDropDown                 matlab.ui.control.DropDown
        StreamDropDownLabel            matlab.ui.control.Label
        FilterPanel                    matlab.ui.container.Panel
        FilterPanelGridLayout          matlab.ui.container.GridLayout
        UpdateButton                   matlab.ui.control.Button
        HighCutoffSpinner              matlab.ui.control.Spinner
        HighCutoffHzSpinnerLabel       matlab.ui.control.Label
        LowCutoffSpinner               matlab.ui.control.Spinner
        LowCutoffHzSpinnerLabel        matlab.ui.control.Label
        OrderSpinner                   matlab.ui.control.Spinner
        OrderSpinnerLabel              matlab.ui.control.Label
        OrderBackground                matlab.ui.control.Label
        HighCutoffBackground           matlab.ui.control.Label
        LowCutoffBackground            matlab.ui.control.Label
        AcquisitionTimeEditField       matlab.ui.control.EditField
        AcquisitionTimeEditFieldLabel  matlab.ui.control.Label
        BufferSizeEditField            matlab.ui.control.NumericEditField
        BufferSizeEditFieldLabel       matlab.ui.control.Label
        FetchButton                    matlab.ui.control.Button
        DisplayedChannelBSpinner       matlab.ui.control.Spinner
        DisplayedChannelBSpinnerLabel  matlab.ui.control.Label
        DisplayedChannelASpinner       matlab.ui.control.Spinner
        DisplayedChannelASpinnerLabel  matlab.ui.control.Label
        FetchLengthPanel               matlab.ui.container.Panel
        FetchLengthPanelGridLayout     matlab.ui.container.GridLayout
        ThresholdSpinner               matlab.ui.control.Spinner
        ThresholdSpinnerLabel          matlab.ui.control.Label
        EstimationMethodDropDown       matlab.ui.control.DropDown
        EstimationMethodDropDownLabel  matlab.ui.control.Label
        EstimationMethodBackground     matlab.ui.control.Label
        ThresholdBackground            matlab.ui.control.Label
        PrestimLengthSpinner           matlab.ui.control.Spinner
        PrestimLengthsecSpinnerLabel   matlab.ui.control.Label
        PoststimLengthSpinner          matlab.ui.control.Spinner
        PoststimLengthsecLabel_2       matlab.ui.control.Label
        StimLengthSpinner              matlab.ui.control.Spinner
        StimLengthsecSpinnerLabel      matlab.ui.control.Label
        PulseWidthBackground           matlab.ui.control.Label
        PoststimLengthBackground       matlab.ui.control.Label
        StimLengthBackground           matlab.ui.control.Label
        PrestimLengthBackground        matlab.ui.control.Label
        PlotTypeTabGroup               matlab.ui.container.TabGroup
        RasterTab                      matlab.ui.container.Tab
        RasterGridLayout               matlab.ui.container.GridLayout
        RasterAxes                     matlab.ui.control.UIAxes
        WaveformTab                    matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        APStreamAxesA                  matlab.ui.control.UIAxes
        WaveformAxesA                  matlab.ui.control.UIAxes
        APStreamAxesB                  matlab.ui.control.UIAxes
        WaveformAxesB                  matlab.ui.control.UIAxes
        PCATab                         matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        PCAAxes                        matlab.ui.control.UIAxes
        FiringRateTab                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        FiringRateAxes                 matlab.ui.control.UIAxes
        TestButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        %% NOTE: In current version, don't run unless SpikeGLX is running
        experimentApp % main app (ReplicationApp.mlapp calls OnlinePlotterApp)


        %bgPool
        
        %isInitialized
        thPool % thread pool used for processing data
        isAcquiring % flag for indicating if data is being fetched
        isStimulation
        hSGL % SpikeGLX client objeect
        s0 % sample count for acquiring data (neuropixel/imec stream)
        s0_ni % sample count for acquiring data (NI stream, for aligning sycn waves)

        % lastConnTime
        % connTimeout

        %dataTimer
        fetchTimer % timer object for fetching data

        
        fetchCallsCnt
        startSamps
        fetchedSamps
        %chunkSize = 3000;
        
        F % future object for processing data with parfeval
        data_uV % filtered AP stream data that is ready for processing, converted to microvolts (i.e. fetch_samples has been filled)
        dataRaw_uV % native AP stream data that is read for processing, converted to microvolts

        % Each fetch call appends new filtered AP stream data to fetchData
        % Once fetchSampCnt >= fetch_samples, fetch_samples of data is
        % exctracted from fetchData and assigned to data_uV
        fetchData
        fetchSampCnt

        fetchDataRaw % same as fetchData but for native AP stream
        fetchRawSampCnt
        
        
        fetchSyncDataNI
        fetchSyncDataNP
        fetchSyncCntNI
        fetchSyncCntNP

        % plots
        hRaster % raster plot
        hStimStartLine %
        hStimEndLine %
        hAPStreamA % AP stream voltage traces, channel A
        hAPStreamB % AP stream voltage traces, channel B
        hAPStreamSpikesA % scatter of locations on AP stream voltage traces of detected spikes, channel A
        hAPStreamSpikesB % scatter of locations on AP stream voltage traces of detected spikes, channel B
        hWaveformA % waveforms of detected spikes, channel A
        hWaveformB % waveforms of detected spikes, channel B

        hFiringRate
        hPCA
        plotType = 'raster'; % indicates to detectSpikes and plotResult(app) which type of processing and plotting is needed
        saveCnt = 0;

        testFlag = 0;
        
        %% optimization properties
        % probably should make a new app as this app is getting crowded
        % Update as of the same night: made a new app (well just a copy of the other one)
        isCollecting
        isOptimizing
        % note: the following struct may likely eat up memory pretty quickly so may have to
        % offload to a mat file if lots of stim trials are performed
        naturalResponse = struct("data_uV", [], "spike_times", [], "spike_chans", [], ...
            "firing_rate", [], "score", [], "cumulative_score", []); %multi-dim struct for holding neural response data from each collected vibrotactile stim event
        collectedEvents = 0;
        naturalScore = []; %trial averaged PCA scores from naturalResponse (will be the last cumulative_score entry before optimization begins)
        optimizationResults
        stimParamRanges = struct("cathode", [1 14], "frequency", [10 200], "amplitude", [10 200]);
        chanMap = [1 2 3 5 6 7 8 9 10 11 12 13 14 15];
        frequencyVals = [10 20 50 100 200];
        maxAmplitudes = [250 200 150 120 100]; %% add to this during experiment
    end
    
    properties (Access = public)
        params % Description
    end

    
    methods (Access = private)
        
        function setDisplayedParameters(app)
            estimation_method_names = fieldnames(app.params.OP.estimation_methods);
            app.EstimationMethodDropDown.Items = estimation_method_names;
            app.ThresholdSpinner.Value = app.params.OP.threshold;
            app.DisplayedChannelASpinner.Value = app.params.NP.plot_chan(1);
            app.DisplayedChannelBSpinner.Value = app.params.NP.plot_chans(2);
            app.APStreamAxesA.Title.String = num2str(app.params.NP.plot_chans(1));
            app.APStreamAxesB.Title.String = num2str(app.params.NP.plot_chans(2));
            app.PrestimLengthSpinner.Value = app.params.OP.prestim_len;
            app.StimLengthSpinner.Value = app.params.OP.stim_len;
            app.PoststimLengthSpinner.Value = app.params.OP.poststim_len;

            app.LowCutoffSpinner.Value = app.params.OP.fcL;
            app.HighCutoffSpinner.Value = app.params.OP.fcH;
            app.OrderSpinner.Value = app.params.OP.n;
            
            %time_axes = get(app, )
            
            %xlim(app.RasterAxes, [-app.params.OP.prestim_len*1000 (app.params.OP.stim_len + app.params.OP.poststim_len)*1000])
        end
        
        function initializeAxes(app)

            time_xlims = [-app.params.OP.prestim_len*1000 (app.params.OP.stim_len + app.params.OP.poststim_len)*1000];
            % raster plot
            hold(app.RasterAxes, "on")
            app.hRaster = plot(app.RasterAxes, [0;0;NaN], 'Color', 'k');
            app.RasterAxes.XLim = time_xlims;
            app.RasterAxes.YLim = [min(app.params.NP.chans) - 0.5 max(app.params.NP.chans) + 0.5];
            
            app.hStimStartLine = xline(app.RasterAxes, 0, "Color", [0.39,0.83,0.07], "LineWidth", 2, "LineStyle", "--");
            app.hStimStartLine.Visible = "off";

            app.hStimEndLine = xline(app.RasterAxes, app.params.OP.stim_len*1000, "Color", [0.64,0.08,0.18], "LineWidth", 2, "LineStyle", '--');
            app.hStimEndLine.Visible = "off";

            % ap stream plot
            [app.APStreamAxesA.XLim,app.APStreamAxesB.XLim] = deal(time_xlims);
            hold(app.APStreamAxesA, "on")
            hold(app.APStreamAxesB, "on")
            app.hAPStreamA = plot(app.APStreamAxesA, zeros(1, app.params.OP.fetch_samples), 'Color', [0.07,0.62,1.00]);
            app.hAPStreamB = plot(app.APStreamAxesB, zeros(1, app.params.OP.fetch_samples), 'Color', [1.00,0.41,0.16]);
            app.hAPStreamSpikesA = scatter(app.APStreamAxesA, [], [], 'r', 'filled');
            app.hAPStreamSpikesB = scatter(app.APStreamAxesB, [], [], 'r', 'filled');
            
            app.hWaveformA = plot(app.WaveformAxesA, zeros(1, app.params.OP.wv_samples), 'Color', [0.07,0.62,1.00]);
            app.hWaveformB = plot(app.WaveformAxesB, zeros(1, app.params.OP.wv_samples), 'Color', [1.00,0.41,0.16]);
            [app.WaveformAxesA.XLim, app.WaveformAxesB.XLim] = deal([1 app.params.OP.wv_samples]);   
            

            % firing rate
            app.hFiringRate = plot(app.FiringRateAxes, app.params.OP.bin_centers, zeros(1, app.params.OP.max_bins));
            app.FiringRateAxes.XLim = [1 app.params.OP.max_bins];%[app.params.OP.bin_centers(1) app.params.OP.bin_centers(end)];

            % pca
            hold(app.PCAAxes, "on")
        end


%%
        function setupAcquisition(app)
            % initialize backgroun pool
            %app.bgPool = backgroundPool;
            app.thPool = parpool('Threads');

            % setup timer
            % app.dataTimer = timer("ExecutionMode", "fixedRate", ...
            %     "BusyMode", "queue", ...
            %     "Period", app.params.OP.fetch_len, ...
            %     "TimerFcn", @app.updatePlot, ...
            %     "ErrorFcn", @app.dataTimerErrorFcn);

            app.fetchTimer = timer("ExecutionMode", "fixedRate", ...
                "BusyMode", "queue", ...
                "Period", app.params.OP.fetch_len/10, ...
                "TimerFcn", @app.fetchChunk, ...
                "ErrorFcn", @app.fetchTimerErrorFcn);

            app.isAcquiring = false;
            app.isStimulation = false;
            app.isOptimizing = false;
            app.isCollecting = false;
            %app.connTimeout = 9; % SpikeGL worker times out ~10seconds
            app.fetchData = [];
            app.fetchSampCnt = 0;
            %app.isInitialized = true;

            app.fetchCallsCnt = 0;
            app.startSamps = zeros(100,1);
            app.fetchedSamps = zeros(100,1);


        end

        function fetchTimerErrorFcn(app, obj, event)
            assignin("base", "obj", obj)
            assignin("base", "event", event)
            data_raw = app.dataRaw_uV;
            data_fetch = app.fetchDataRaw;
            param = app.params;
            assignin("base", "data_raw", data_raw)
            assignin("base", "data_fetch", data_fetch)
            assignin("base", "param", param)
            if app.isStimulation
                data_sy = app.fetchSyncDataNP(1:app.params.NP.sync_samples,:);
                data_ni = app.fetchSyncDataNI(1:app.params.NI.sync_samples,:);
                assignin("base", "data_sy", data_sy)
                assignin("base", "data_ni", data_ni)
                endStimAndFetch(app)
            else
                stopAcquisition(app)
            end

        end
        
        function ensureConnection(app)
            % check if 
            app.hSGL = SpikeGL(app.params.address);
            %app.s0 = GetStreamSampleCount(app.hSGL, app.params.NP.js, app.params.NP.ip);
        end

        function startAcquisition(app)
            app.hStimStartLine.Visible = "off";
            app.hStimEndLine.Visible = "off";
            app.isAcquiring = true;
            app.isStimulation = false;
            app.FetchButton.Text = 'Stop';
            app.fetchTimer.TimerFcn = @app.fetchChunk;
            app.fetchTimer.Period = app.params.OP.fetch_len / 10;
            app.fetchTimer.StartDelay = 0;
            app.fetchData = [];
            app.fetchSampCnt = 0;

            app.fetchDataRaw = [];
            app.fetchRawSampCnt = 0;
            ensureConnection(app)
            app.s0 = GetStreamSampleCount(app.hSGL, app.params.NP.js, app.params.NP.ip);
            %start(app.dataTimer)
            start(app.fetchTimer)


        end

        function stopAcquisition(app)
            app.isAcquiring = false;
            app.FetchButton.Text = 'Fetch';
            %stop(app.dataTimer)
            stop(app.fetchTimer)
            Close(app.hSGL);
        end

        function startStimAndFetch(app)
            app.isAcquiring = true;
            app.isStimulation = true;
            app.StimulateandFetchButton.Text = 'Acquiring...';
            app.fetchData = [];
            app.fetchSampCnt = 0;
            app.fetchDataRaw = [];
            app.fetchRawSampCnt = 0;
app.params.OP.sync_len
            app.fetchSyncDataNI = [];
            app.fetchSyncDataNP = [];
            app.fetchSyncCntNI = 0;
            app.fetchSyncCntNP = 0;
            app.fetchTimer.TimerFcn = @app.fetchSyncWave;
            app.fetchTimer.Period = app.params.OP.sync_len / 6;
            app.fetchTimer.StartDelay = 0;
            ensureConnection(app)
            % remove the "+1" for real experiment
            app.s0 = GetStreamSampleCount(app.hSGL, app.params.NP.js, app.params.NP.ip);
            app.s0_ni = round(double(app.s0) / app.params.NP.fs * app.params.NI.fs);
            app.s0
            start(app.fetchTimer)
            switch app.params.OP.stim_type
                case 'nat'
                    writeline(app.experimentApp.ardObj, app.experimentApp.ardComDict{'vibration_trigger'})
                case 'scs'
                    cmd_cell = app.experimentApp.runningStimParams.cmd_cell;
                    pause(app.experimentApp.runningArdComSettings.buffer_delay / 1000) % quick solution for slight delay after calling GetStreamSampleCount for SCS
                    %app.updateDisplay('Delivering stimulation...', 'Ripple')
                    for i = 1:length(cmd_cell)
                        xippmex('stimseq', cmd_cell{i})
                    end

            end
            

        end
        
        function endStimAndFetch(app)
            stop(app.fetchTimer)
            app.hStimStartLine.Visible = "on";
            app.hStimEndLine.Visible = "on";
            app.isAcquiring = false;
            app.isStimulation = false;
            app.StimulateandFetchButton.Text = 'Stimulate and Fetch';
            Close(app.hSGL);

        end
        
        function startOptimization(app)
            app.isAcquiring = true;
            app.isStimulation = false;
            app.isOptimizing = true;
            app.OptimizeButton.Text = 'Optimizing...';
            ensureConnection(app)
            
            % setup optimization


            app.optimizationResults = bayesopt(@(optParams) brainStateObjectiveFcn(app, optParams), ...
                initializeBOvariables(app.stimParamRanges), ...
                "AcquisitionFunctionName", "expected-improvement-plus", "PlotFcn", "all");
                % "XConstraintFcn", @(X)amplitudeConstraintFcn(X, app.frequencyVals, app.maxAmplitudes), ...
                %"PlotFcn", "all");

            endOptimization(app)


        end
        function endOptimization(app)
            app.isAcquiring = false;
            app.isOptimizing = false;
            app.OptimizeButton.Text = 'Optimize';
            Close(app.hSGL)

        end

        function avg_dist = brainStateObjectiveFcn(app, optParams)
            % initialize data collection
            app.fetchData = [];
            app.fetchSampCnt = 0;
            app.fetchDataRaw = [];
            app.fetchRawSampCnt = 0;

            app.fetchSyncDataNI = [];
            app.fetchSyncDataNP = [];
            app.fetchSyncCntNI = 0;
            app.fetchSyncCntNP = 0;
            app.params.NI.stim_word = 2;
            app.params.OP.stim_type = 'scs';
            % get stim params to be evaluated
            % may do something different but this does allow for
            % scalability (or reducibility) of optimizable parameters
            % also may want to add updating the display in the
            % ReplicationApp
            stim_param_names = {'cathode';'frequency';'amplitude'};%fieldnames(optParams);

            app.experimentApp.updateDisplay('Selected Parameters:', 'Optimize')
            for i = 1:length(stim_param_names)
                app.experimentApp.runningStimParams.(stim_param_names{i}) = optParams.(stim_param_names{i});
                app.experimentApp.updateDisplay([char(stim_param_names{i}) ': ' num2str(optParams.(stim_param_names{i}))], 'Optimize')
            end
            app.experimentApp.runningStimParams.cathode = app.chanMap(optParams.cathode);
            app.experimentApp.runningStimParams.anode = 4;
            app.experimentApp.runningStimParams.cmd_cell = generateSpatiotemporalPattern(app.experimentApp.runningStimParams);
            app.experimentApp.clearElectrodeGrid;
            app.experimentApp.setStimulationPattern(app.experimentApp.runningStimParams) %this may work
            % get starting sample counts
            app.s0 = GetStreamSampleCount(app.hSGL, app.params.NP.js, app.params.NP.ip);
            app.s0_ni = round(double(app.s0) / app.params.NP.fs * app.params.NI.fs);
            
            % stim
            cmd_cell = app.experimentApp.runningStimParams.cmd_cell;
            pause(app.experimentApp.runningArdComSettings.buffer_delay / 1000) % quick solution for slight delay after calling GetStreamSampleCount for SCS
            for i = 1:length(cmd_cell)
                xippmex('stimseq', cmd_cell{i})
            end
            
            % hoping this next section works. well, hoping any of it will
            % work. Trying to figure out how to use bayesopt final output
            % as prior to consecutive bayesopt calls but this may be
            % functional. The issue is that bayesopt must not be stopped
            % and then re-called as of now or else all info about the GP
            % and objective evaluations are lost (basically will restart
            % from scratch)
            % get sync wave and map stim event to imec stream
            sync_acquired = false;
            while ~sync_acquired
                sync_acquired = app.fetchSyncWave;
                pause(app.params.OP.sync_len / 6)
            end

            % get data
            % I believe this will plot in parallel and then
            % do what happens here (maybe)
            data_acquired = false;
            while ~data_acquired
                data_acquired = app.fetchChunk;
                pause(app.params.OP.fetch_len / 10)
            end
            results = detectSpikes(app.data_uV, app.params, 'pca');

            %% y_nat and y_scs consist of the first n scores of PCA performed on firing rates during stimulation
            % Rows of y_nat (naturalScore) and y_scs (results{5}') correspond to each score
            % euclidian distance between the neural trajectory at each time point is calculated 
            % and then averaged. This is the metric to be minimized. Will
            % impliment n-scores but for now will leave at 3.
            avg_dist = mean(vecnorm(app.naturalScore(:,1:3)'-results{5}(:,1:3)'));
            pause(4)

        end

        function plotResult(app, result)
            %app.AcquisitionTimeEditField.Value = 'here2';
            %spike_times = result(:,1);%app.params.OP.time_ms(result(:,1));
            %spike_chans = result(:,2); %app.params.NP.chan_order(result(:,2)) - 1;
            %
            switch result{end}%app.plotType
                case 'raster'
                    set(app.hRaster, "XData", result{1}, "YData", result{2})


                case 'waveform'
                    chanA = app.params.NP.plot_chan_inds(1);
                    chanB = app.params.NP.plot_chan_inds(2);
                    % data = result{1};
                    % spike_times = result{2};
                    % spike_chans = result{3};
                    set(app.hAPStreamA, "XData", app.params.OP.time_ms, "YData", result{1}(:, chanA))
                    set(app.hAPStreamB, "XData", app.params.OP.time_ms, "YData", result{1}(:, chanB))
                    
                    % brutally unreadable if it works
                    set(app.hAPStreamSpikesA, "XData", app.params.OP.time_ms(result{2}(result{3}==chanA)), ...
                        "YData", result{1}(result{2}(result{3}==chanA), chanA))

                    set(app.hAPStreamSpikesB, "XData", app.params.OP.time_ms(result{2}(result{3}==chanB)), ...
                        "YData", result{1}(result{2}(result{3}==chanB), chanB))

                    set(app.hWaveformA, "XData", 1:app.params.OP.wv_samples, ...
                        "YData", mean(result{4}(:, result{3}==chanA), 2))

                    set(app.hWaveformB, "XData", 1:app.params.OP.wv_samples, ...
                        "YData", mean(result{4}(:, result{3}==chanB), 2))
                
                case 'firing_rate'
                    loc=find(app.params.OP.bin_centers==min(abs(app.params.OP.bin_centers)))-1;
                    baseline = result{4}(1:loc,:);
                    contourf(app.FiringRateAxes, result{4}(:,app.params.NP.chans+1)')
                    colormap(jet(6))
                    xline(app.FiringRateAxes, find(app.params.OP.bin_centers==min(abs(app.params.OP.bin_centers))), "Color", [0.39,0.83,0.07], "LineWidth", 2, "LineStyle", "--")
                
                case 'pca'
                    %score = smooth(result{5});
                    pc1 = smooth(result{5}(:,1));
                    pc2 = smooth(result{5}(:,2));
                    pc3 = smooth(result{5}(:,3));
                    start_loc=find(app.params.OP.bin_centers==min(abs(app.params.OP.bin_centers)))-1;
                    
                    end_loc=find(app.params.OP.bin_edges == app.params.OP.stim_len*10^3);
                    app.params.OP.bin_edges(start_loc)
                    app.params.OP.bin_edges(end_loc)
                    hold(app.PCAAxes, "off")
                    app.hPCA = plot3(app.PCAAxes, pc1, pc2, pc3);
                    hold(app.PCAAxes, "on")
                    plot3(app.PCAAxes, pc1(1), pc2(1), pc3(1), 'ko')
                    plot3(app.PCAAxes, pc1(start_loc), pc2(start_loc), pc3(start_loc), 'go')
                    plot3(app.PCAAxes, pc1(end_loc), pc2(end_loc), pc3(end_loc), 'ro')
                    %annotation(app.PCAAxes, 'arrow', [pc1(end),pc2(end), pc3(end)]);
                    if app.isCollecting
                        app.collectedEvents = app.collectedEvents + 1;
                        cnt = app.collectedEvents;
                        app.naturalResponse(cnt).data_uV = result{1};
                        app.naturalResponse(cnt).spike_times = result{2};
                        app.naturalResponse(cnt).spike_chans = result{3};
                        app.naturalResponse(cnt).firing_rate = result{4};
                        app.naturalResponse(cnt).score = result{5};
                        firing_rate_avg = mean(cat(3, app.naturalResponse.firing_rate), 3);
                        [~, app.naturalResponse(cnt).cumulative_score, ~, ~, ~] = pca(firing_rate_avg);
                        app.naturalScore = app.naturalResponse(end).cumulative_score;
                    end

                otherwise
                    return
            end


        end
        function sendToWorker(app)

            % extract fetch_samples from fetched data
            app.data_uV = app.fetchData(1:app.params.OP.fetch_samples, :);
            % trim fetched data
            % technically should be min([app.params.OP.fetch_samples+1, app.params.OP.fetch_samples])
            % in the case where fetchData is exactly fetch_samples in length
            app.fetchData = app.fetchData(app.params.OP.fetch_samples+1:end, :);
            app.fetchSampCnt = size(app.fetchData, 1);
            
            app.dataRaw_uV = app.fetchDataRaw(1:app.params.OP.fetch_samples,:);
            app.fetchDataRaw = app.fetchDataRaw(app.params.OP.fetch_samples+1:end, :);
            app.fetchRawSampCnt = size(app.fetchDataRaw, 1);
            
            % app.saveCnt = app.saveCnt + 1;
            % fname = ['E:\SGL_DATA\vibration_stim_3-25-25_' num2str(app.saveCnt) '.mat'];
            % data = app.data_uV;
            % dataRaw = app.dataRaw_uV;
            % save(fname, "data", "dataRaw")
            % send to worker for processing
            if app.isCollecting 
                app.plotType = 'pca';
            end
            app.F = parfeval(app.thPool, @detectSpikes, 1, app.data_uV, app.params, app.plotType);
            afterEach(app.F, @app.plotResult, 0);

            %app.EditField.Value = length(app.thPool.FevalQueue.RunningFutures);%.QueuedFutures
            app.BufferSizeEditField.Value = length(app.fetchData);

        end

        function updateWaveform(app)
            app.F = parfeval(app.thPool, @detectSpikes, 1, app.data_uV, app.params, app.plotType);
            afterEach(app.F, @app.plotResult, 0);
            
        end
        
        function varargout = fetchChunk(app, ~, ~)            
            if ~app.isAcquiring
                return;
            end
            

            % fetch_samples probably bad naming scheme here but this represents the
            % number of samples that analysis uses (spikeDetection, raster plots, etc.)
            % I named this when I didn't think it was feasible to reliably
            % and continuosly fetch data and used pausing to fill up the
            % SpikeGLX buffer and then request fetch_samples at one time.
            % If not enough samples were returned, fetch would be called
            % again until the desired number of samples was achieved (see
            % fetch_and_bin_test.m for my first attempt).
            % My plan for collecting data around stimulus events was to do this 
            % (see vibration_event_test.m)  
            %
            % Luckily I learned how to use a timer and parpool which currently seem to work very well.
            % The fetchTimer calls this function at a period set to fetch_len/10 (could try other values) so that
            % the returned samples will always be less than fetch_samples.
            % The starting sample count (s0) for the next fetch is then updated based on the returned number of samples (mi). 
            % -> if the requested samples was <= the timer period (or even just close to the timer period since it's matlab's clock)
            % all requested samples could be returned while the SpikeGLX buffer still has available samples. 
            % Each consecutive update of s0 and fetch call will start to
            % lag behind the current sample count until: Warning: ERROR FETCH: Too late.
            % The requested s0 is now in the past, outside of the buffer.
            % Here, all available samples are returned and appended to
            % fetchData (not a buffer on MATLAB's side but was thinking
            % about doing something like that). If length of fetchData >=
            % fetch_samples (it will be very unlikely for fetchData to equal fetch_samples),
            % an update function is called to exctract and trim fetch_samples from fetchData and send them
            % for processing on a parpool worker. Plots are updated once
            % processing completes. 
            disp('here')
            [data, ct] = Fetch(app.hSGL, ...
                app.params.NP.js_filtered*app.params.NP.js, ...
                app.params.NP.ip + app.testFlag, ...
                app.s0, ...
                app.params.OP.fetch_samples, ... 
                app.params.NP.chans);
            [mi, ~] = size(data);
            
            % checking to see if this works, get raw stream at the same
            % time
            [data_raw, ~] = Fetch(app.hSGL, ...
                app.params.NP.js, ...
                app.params.NP.ip + app.testFlag, ...
                app.s0, ...
                mi, ... 
                app.params.NP.chans);
            [mi_raw, ~] = size(data_raw);

            % store data
            app.fetchData = [app.fetchData; double(data)*app.params.NP.i16uVmult];
            app.fetchSampCnt = app.fetchSampCnt + mi;
            
            app.fetchDataRaw = [app.fetchDataRaw; double(data_raw)*app.params.NP.i16uVmult];
            app.fetchRawSampCnt = app.fetchRawSampCnt + mi_raw;

            t = seconds(app.s0/app.params.NP.fs);
            t.Format = 'mm:ss.SSS';
            app.AcquisitionTimeEditField.Value = string(t);

            app.s0 = app.s0 + mi;
            disp(['s0: ' num2str(app.s0)])
            disp(['ct+mi' num2str(ct+mi)])

            if app.fetchSampCnt >= app.params.OP.fetch_samples
                sendToWorker(app)
                if app.isStimulation
                    
                    endStimAndFetch(app)
                elseif app.isOptimizing
                    varargout{1} = true;
                end
            else
                varargout{1} = false;
            end
            % for testing
            % app.fetchCallsCnt = app.fetchCallsCnt + 1;
            % if app.fetchCallsCnt > length(app.startSamps)
            %     stopAcquisition(app)
            %     return;
            % end
            % 
            % app.startSamps(app.fetchCallsCnt) = app.s0 - mi;
            % app.fetchedSamps(app.fetchCallsCnt) = mi;

        end
        

        function varargout = fetchSyncWave(app, ~, ~)
            % remove the "+1" for real experiment
            [data_sy, si_sy] = Fetch(app.hSGL, app.params.NP.js, app.params.NP.ip, ...
                app.s0, app.params.NP.sync_samples, app.params.NP.chan_sy);
            [mi, ~] = size(data_sy);

            [data_ni, si_ni] = Fetch(app.hSGL, app.params.NI.js, app.params.NI.ip, ... 
                app.s0_ni, app.params.NI.sync_samples, app.params.NI.chans);
            [mi_ni, ~] = size(data_ni);

            app.fetchSyncDataNP = [app.fetchSyncDataNP; data_sy];
            app.fetchSyncDataNI = [app.fetchSyncDataNI; data_ni];

            app.fetchSyncCntNP = app.fetchSyncCntNP + mi;
            app.fetchSyncCntNI = app.fetchSyncCntNI + mi_ni;

            app.s0 = app.s0 + mi;
            app.s0_ni = app.s0_ni + mi_ni;

            if (app.fetchSyncCntNP >= app.params.NP.sync_samples) && (app.fetchSyncCntNI >= app.params.NI.sync_samples)
                assignin("base", "si_sy", si_sy)
                assignin("base", "si_ni", si_ni)
                stop(app.fetchTimer)
                data_sy = app.fetchSyncDataNP(1:app.params.NP.sync_samples,:);
                data_ni = app.fetchSyncDataNI(1:app.params.NI.sync_samples,:);
                app.s0 = extractEventSample(data_sy, data_ni, si_sy, si_ni, app.params);
                if ~app.isOptimizing
                    app.fetchTimer.TimerFcn = @app.fetchChunk;
                    app.fetchTimer.Period = app.params.OP.fetch_len / 10;
                    app.fetchTimer.StartDelay = 0;
                    start(app.fetchTimer)
                elseif app.isOptimizing
                    varargout{1} = true;
                end
            else
                varargout{1} = false;
                
            end


        end


        % function updateBuffer(app, f)
        %     [data, new_s0] = fetchOutputs(f);
        %     if ~isempty(data)
        %         app.fetchData = [app.fetchData; data];
        %         app.fetchSampCnt = app.fetchSampCnt + size(data,1);
        %         app.s0 = new_s0;
        %     end
        % 
        % end


    end
    
    methods (Access = public)
        
        function updateFetchLengthDisplay(app, type)
            param_len_name = [type '_len'];
            %param_samples_name = [type '_samples'];
            component = findobj(app.FetchLengthPanel, "Tag", type);
            component.Value = app.params.OP.(param_len_name);
            if strcmp(type, "stim")
                app.hStimEndLine.Value = app.params.OP.(param_len_name) * 1000;
            end
            [app.RasterAxes.XLim, app.APStreamAxesA.XLim, app.APStreamAxesB.XLim] = deal([-app.params.OP.prestim_len*1000 (app.params.OP.stim_len + app.params.OP.poststim_len)*1000]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, opts)
            arguments
                app (1,1) OnlineOptimizerApp
                opts.mainApp
                opts.params

            end
            if isfield(opts, "mainApp")
                app.experimentApp = opts.mainApp;
                app.params = opts.params;
                app.StimLengthSpinner.Enable = "off";
                app.StimulateandFetchButton.Enable = "on";
            else
                app.experimentApp = [];
                addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'utils')))
                app.params = generateAcquisitionParameters();
            end
            [app.params, msg] = initializeAcquisitionParameters(app.params);
            app.experimentApp.acquisitionParams = app.params;
            msg
            setDisplayedParameters(app)
            initializeAxes(app)
            setupAcquisition(app)

        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            %app.FetchButton.Enable = "off";
            %fetchData(app)
            if ~app.isAcquiring
                startAcquisition(app)
            else
                stopAcquisition(app)
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            % app.fetchCallsCnt = 0;
            % % app.fetchSampCnt = 0;
            % % 
            % start_samples = app.startSamps;
            % fetched_samples = app.fetchedSamps;
            % assignin("base", "fetched_samples", fetched_samples)
            % assignin("base", "start_samples", start_samples)
            % f = app.F;
            % assignin("base", "F", f)
            % app.fetchTimer.Period
            % data = app.data_uV;
            % dataraw = app.dataRaw_uV;
            % param = app.params;
            % assignin("base", "params", param)
            % assignin("base", "data_uV", data)
            % assignin("base", "dataraw", dataraw)
            % fd = app.fetchData;
            % assingin("base", "fd", fd)
            % ax = app.RasterAxes;
            % assignin("base", "ax", ax)
            % th = app.thPool;
            % assignin("base", "th", th)
            % dt = app.dataTimer;
            % assignin("base", "dt", dt)
            %app.params.NP.plot_chan_inds
            % detectSpikes(app.data_uV, app.params)
            %app.params.NI.sync_samples
            % nr= app.naturalResponse;
            % assignin("base", "nr", nr)
            %endOptimization(app)
            app.fetchTimer.AveragePeriod
            % optimizationData = struct('naturalResponse', app.naturalResponse, 'collectedEvents', app.collectedEvents, 'naturalScore', app.naturalScore, 'optimizationResults', app.optimizationResults, ...
            %     'params', app.params);
            % save("E:\SGL_DATA\rec_4-8-25\optimizationData.mat", "optimizationData", '-mat')
            % app.params.NI.stim_word
        end

        % Value changed function: EstimationMethodDropDown
        function EstimationMethodDropDownValueChanged(app, event)
            value = app.EstimationMethodDropDown.Value;
            app.params.OP.estimation_method = value;
            app.params.OP.estimationFcn = app.params.OP.estimation_methods.(value);
            if ~isempty(app.experimentApp)
                app.experimentApp.acquisitionParams = app.params;
            end
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            value = app.ThresholdSpinner.Value;
            app.params.OP.threshold = value;
            if ~isempty(app.experimentApp)
                app.experimentApp.acquisitionParams = app.params;
            end
        end

        % Value changed function: DisplayedChannelASpinner, 
        % ...and 1 other component
        function DisplayedChannelSpinnerValueChanged(app, event)
            %value = app.DisplayedChannelASpinner.Value;
            value = event.Source.Value;
            
            %str2double(event.Source.Tag);
            val_loc = logical(str2double(split(event.Source.Tag))'); %this is dumb, set tags to '0 1' and '1 0'
            value_p = app.params.NP.plot_chans(~val_loc);
            if value == value_p
                prev_value = event.PreviousValue;
                if value == app.params.NP.num_chans - 1 || value == 0
                    value = prev_value;

                elseif prev_value < value
                    value = value + 1;
                else
                    value = value - 1;
                end
                event.Source.Value = value;
            end
            axis_names = {'A', 'B'};
            app.(['APStreamAxes' axis_names{val_loc}]).Title.String = num2str(value);
            app.params.NP.plot_chans(val_loc) = value;
            app.params.NP.plot_chan_inds(val_loc) = value + 1;
            if ~isempty(app.experimentApp)
                app.experimentApp.acquisitionParams = app.params;
            end
            if ~app.isAcquiring && strcmp(app.PlotTypeTabGroup.SelectedTab.Tag, 'waveform')
                updateWaveform(app)
            end
            % app.params.NP.plot_chans
            % app.params.NP.plot_chan_inds
        end

        % Value changed function: PoststimLengthSpinner, 
        % ...and 2 other components
        function FetchLengthSpinnerValueChanged(app, event)
            value = event.Source.Value;
            val_tag = event.Source.Tag;
            app.params.OP.([val_tag '_len']) = value;
            % update total length and samples
            app.params.OP.fetch_len = app.params.OP.prestim_len + app.params.OP.stim_len + app.params.OP.poststim_len;
            %app.dataTimer.Period = app.params.OP.fetch_len;
            app.fetchTimer.Period = app.params.OP.fetch_len / 10;
            if app.params.initialized
                app.params.OP.([val_tag '_samples']) = round(value * app.params.NP.fs);
                app.params.OP.fetch_samples = app.params.OP.prestim_samples + app.params.OP.stim_samples + app.params.OP.poststim_samples;
                app.params.OP.max_bins = app.params.OP.fetch_samples / app.params.OP.bin_samples;
    
                app.params.OP.time_ms = ((0:app.params.OP.fetch_samples-1) - app.params.OP.prestim_samples) / round(app.params.NP.fs) * 1000;
                app.params.OP.bin_edges = app.params.OP.time_ms(1):app.params.OP.bin_size*10^3:(app.params.OP.time_ms(end)+10^3/round(app.params.NP.fs));
                app.params.OP.bin_centers = app.params.OP.bin_edges(2:end) - app.params.OP.bin_size*10^3/2;
            end
            if ~isempty(app.experimentApp)
                app.experimentApp.acquisitionParams = app.params;
            end
            [app.RasterAxes.XLim, app.APStreamAxesA.XLim, app.APStreamAxesB.XLim] = deal([-app.params.OP.prestim_len*1000 (app.params.OP.stim_len + app.params.OP.poststim_len)*1000]);
            app.FiringRateAxes.XLim = [1 app.params.OP.max_bins];
            % app.params.OP.fetch_len
            % app.params.OP.fetch_samples
        end

        % Selection change function: PlotTypeTabGroup
        function PlotTypeTabGroupSelectionChanged(app, event)
            selectedTab = app.PlotTypeTabGroup.SelectedTab;
            app.plotType = selectedTab.Tag;


        end

        % Value changed function: StreamDropDown
        function StreamDropDownValueChanged(app, event)
            value = app.StreamDropDown.Value;
            if value
                app.params.NP.js_filtered = value;
                app.ApplyFilterCheckBox.Enable = "on";
            else
                app.params.NP.js_filtered = -1;
                app.ApplyFilterCheckBox.Enable = "off";
                
                %app.ApplyFilterCheckBox.Value = 0;
            end
            if ~app.isAcquiring
                if value
                    data = app.dataRaw_uV;
                else
                    data = app.data_uV;
                end
                app.F = parfeval(app.thPool, @detectSpikes, 1, data, app.params, 'raster');
                afterEach(app.F, @app.plotResult, 0);
            end
        end

        % Value changed function: ApplyFilterCheckBox
        function ApplyFilterCheckBoxValueChanged(app, event)
            value = app.ApplyFilterCheckBox.Value;
            app.params.OP.apply_filter = value;
            app.FilterPanel.Enable = value;
            %app.params.OP.apply_filter
            if ~app.isAcquiring
                app.F = parfeval(app.thPool, @detectSpikes, 1, app.dataRaw_uV, app.params, 'raster');
                afterEach(app.F, @app.plotResult, 0);
            end
        end

        % Button pushed function: StimulateandFetchButton
        function StimulateandFetchButtonPushed(app, event)
            startStimAndFetch(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isAcquiring
                stopAcquisition(app)
            end
            % Clean up timers
            % if ~isempty(app.dataTimer) && isvalid(app.dataTimer)
            %     stop(app.dataTimer);
            %     delete(app.dataTimer);
            % end
            if ~isempty(app.fetchTimer) && isvalid(app.fetchTimer)
                stop(app.fetchTimer);
                delete(app.fetchTimer);
            end
            % Delete background pool
            % if ~isempty(app.bgPool)
            %     delete(app.bgPool);
            % end
            if ~isempty(app.thPool)
                delete(app.thPool)
            end

            if ~isempty(app.experimentApp)
                app.experimentApp.PlotterButton.Value = 0;
            end
            delete(app)
            
        end

        % Value changed function: CollectNaturalStimCheckBox
        function CollectNaturalStimCheckBoxValueChanged(app, event)
            % selects whether next stimulate and fetch will add natural
            % stim data to
            value = app.CollectNaturalStimCheckBox.Value;
            app.isCollecting = value;
            if value && ~strcmp(app.params.OP.stim_type, 'nat')
                app.params.OP.stim_type = 'nat';
                app.params.NI.stim_word = app.params.NI.nat_stim_word;
            end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'nat'
                    app.params.OP.stim_type = 'nat';
                    app.params.NI.stim_word = app.params.NI.nat_stim_word;
                case 'scs'
                    app.params.OP.stim_type = 'scs';
                    app.params.NI.stim_word = app.params.NI.scs_stim_word;
            end
app.params.NI.stim_word
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            startOptimization(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TestButton = uibutton(app.GridLayout, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.PlotTypeTabGroup = uitabgroup(app.GridLayout);
            app.PlotTypeTabGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeTabGroupSelectionChanged, true);
            app.RasterTab = uitab(app.PlotTypeTabGroup);
            app.RasterTab.Title = 'Raster';
            app.RasterTab.Tag = 'raster';
            app.RasterGridLayout = uigridlayout(app.RasterTab);
            app.RasterAxes = uiaxes(app.RasterGridLayout);
            title(app.RasterAxes, 'Raster')
            xlabel(app.RasterAxes, 'Time (msec)')
            ylabel(app.RasterAxes, 'Channels')
            zlabel(app.RasterAxes, 'Z')
            app.WaveformTab = uitab(app.PlotTypeTabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.WaveformTab.Tag = 'waveform';
            app.GridLayout2 = uigridlayout(app.WaveformTab);
            app.WaveformAxesB = uiaxes(app.GridLayout2);
            xlabel(app.WaveformAxesB, 'Samples')
            ylabel(app.WaveformAxesB, 'Voltage (μV)')
            zlabel(app.WaveformAxesB, 'Z')
            app.APStreamAxesB = uiaxes(app.GridLayout2);
            title(app.APStreamAxesB, 'Channel B')
            xlabel(app.APStreamAxesB, 'Time (msec)')
            ylabel(app.APStreamAxesB, 'Voltage (μV)')
            zlabel(app.APStreamAxesB, 'Z')
            app.WaveformAxesA = uiaxes(app.GridLayout2);
            xlabel(app.WaveformAxesA, 'Samples')
            ylabel(app.WaveformAxesA, 'Voltage (μV)')
            zlabel(app.WaveformAxesA, 'Z')
            app.APStreamAxesA = uiaxes(app.GridLayout2);
            title(app.APStreamAxesA, 'Channel A')
            xlabel(app.APStreamAxesA, 'Time (msec)')
            ylabel(app.APStreamAxesA, 'Voltage (μV)')
            zlabel(app.APStreamAxesA, 'Z')
            app.PCATab = uitab(app.PlotTypeTabGroup);
            app.PCATab.Title = 'PCA';
            app.PCATab.Tag = 'pca';
            app.GridLayout4 = uigridlayout(app.PCATab);
            app.PCAAxes = uiaxes(app.GridLayout4);
            title(app.PCAAxes, 'Title')
            xlabel(app.PCAAxes, 'X')
            ylabel(app.PCAAxes, 'Y')
            zlabel(app.PCAAxes, 'Z')
            app.FiringRateTab = uitab(app.PlotTypeTabGroup);
            app.FiringRateTab.Title = 'Firing Rate';
            app.FiringRateTab.Tag = 'firing_rate';
            app.GridLayout3 = uigridlayout(app.FiringRateTab);
            app.FiringRateAxes = uiaxes(app.GridLayout3);
            title(app.FiringRateAxes, 'Title')
            xlabel(app.FiringRateAxes, 'X')
            ylabel(app.FiringRateAxes, 'Y')
            zlabel(app.FiringRateAxes, 'Z')
            app.FetchLengthPanel = uipanel(app.GridLayout);
            app.FetchLengthPanelGridLayout = uigridlayout(app.FetchLengthPanel);
            app.PrestimLengthBackground = uilabel(app.FetchLengthPanelGridLayout);
            app.PrestimLengthBackground.Text = '';
            app.StimLengthBackground = uilabel(app.FetchLengthPanelGridLayout);
            app.StimLengthBackground.Text = '';
            app.PoststimLengthBackground = uilabel(app.FetchLengthPanelGridLayout);
            app.PoststimLengthBackground.Text = '';
            app.PulseWidthBackground = uilabel(app.FetchLengthPanelGridLayout);
            app.PulseWidthBackground.Text = '';
            app.StimLengthsecSpinnerLabel = uilabel(app.FetchLengthPanelGridLayout);
            app.StimLengthsecSpinnerLabel.Text = 'Stim Length (sec)';
            app.StimLengthSpinner = uispinner(app.FetchLengthPanelGridLayout);
            app.StimLengthSpinner.Step = 0.05;
            app.StimLengthSpinner.Limits = [0 3];
            app.StimLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @FetchLengthSpinnerValueChanged, true);
            app.StimLengthSpinner.Tag = 'stim';
            app.StimLengthSpinner.Value = 1;
            app.PoststimLengthsecLabel_2 = uilabel(app.FetchLengthPanelGridLayout);
            app.PoststimLengthsecLabel_2.Text = 'Poststim Length (sec)';
            app.PoststimLengthSpinner = uispinner(app.FetchLengthPanelGridLayout);
            app.PoststimLengthSpinner.Step = 0.05;
            app.PoststimLengthSpinner.Limits = [0 1.5];
            app.PoststimLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @FetchLengthSpinnerValueChanged, true);
            app.PoststimLengthSpinner.Tag = 'poststim';
            app.PrestimLengthsecSpinnerLabel = uilabel(app.FetchLengthPanelGridLayout);
            app.PrestimLengthsecSpinnerLabel.Text = 'Prestim Length (sec)';
            app.PrestimLengthSpinner = uispinner(app.FetchLengthPanelGridLayout);
            app.PrestimLengthSpinner.Step = 0.05;
            app.PrestimLengthSpinner.Limits = [0 1.5];
            app.PrestimLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @FetchLengthSpinnerValueChanged, true);
            app.PrestimLengthSpinner.Tag = 'prestim';
            app.ThresholdBackground = uilabel(app.FetchLengthPanelGridLayout);
            app.ThresholdBackground.Text = '';
            app.EstimationMethodBackground = uilabel(app.FetchLengthPanelGridLayout);
            app.EstimationMethodBackground.Text = '';
            app.EstimationMethodDropDownLabel = uilabel(app.FetchLengthPanelGridLayout);
            app.EstimationMethodDropDownLabel.Text = 'Estimation Method';
            app.EstimationMethodDropDown = uidropdown(app.FetchLengthPanelGridLayout);
            app.EstimationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @EstimationMethodDropDownValueChanged, true);
            app.ThresholdSpinnerLabel = uilabel(app.FetchLengthPanelGridLayout);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.FetchLengthPanelGridLayout);
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.DisplayedChannelASpinnerLabel = uilabel(app.GridLayout);
            app.DisplayedChannelASpinnerLabel.Text = 'Displayed Channel A';
            app.DisplayedChannelASpinner = uispinner(app.GridLayout);
            app.DisplayedChannelASpinner.Limits = [0 383];
            app.DisplayedChannelASpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayedChannelSpinnerValueChanged, true);
            app.DisplayedChannelASpinner.Tag = '1 0';
            app.DisplayedChannelBSpinnerLabel = uilabel(app.GridLayout);
            app.DisplayedChannelBSpinnerLabel.Text = 'Displayed Channel B';
            app.DisplayedChannelBSpinner = uispinner(app.GridLayout);
            app.DisplayedChannelBSpinner.Limits = [0 383];
            app.DisplayedChannelBSpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayedChannelSpinnerValueChanged, true);
            app.DisplayedChannelBSpinner.Tag = '0 1';
            app.FetchButton = uibutton(app.GridLayout, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.BufferSizeEditFieldLabel = uilabel(app.GridLayout);
            app.BufferSizeEditFieldLabel.Text = 'Buffer Size';
            app.BufferSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.AcquisitionTimeEditFieldLabel = uilabel(app.GridLayout);
            app.AcquisitionTimeEditFieldLabel.Text = 'Acquisition Time ';
            app.AcquisitionTimeEditField = uieditfield(app.GridLayout, 'text');
            app.FilterPanel = uipanel(app.GridLayout);
            app.FilterPanel.Enable = 'off';
            app.FilterPanelGridLayout = uigridlayout(app.FilterPanel);
            app.LowCutoffBackground = uilabel(app.FilterPanelGridLayout);
            app.LowCutoffBackground.Text = '';
            app.HighCutoffBackground = uilabel(app.FilterPanelGridLayout);
            app.HighCutoffBackground.Text = '';
            app.OrderBackground = uilabel(app.FilterPanelGridLayout);
            app.OrderBackground.Text = '';
            app.OrderSpinnerLabel = uilabel(app.FilterPanelGridLayout);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.FilterPanelGridLayout);
            app.LowCutoffHzSpinnerLabel = uilabel(app.FilterPanelGridLayout);
            app.LowCutoffHzSpinnerLabel.Text = 'Low Cutoff (Hz)';
            app.LowCutoffSpinner = uispinner(app.FilterPanelGridLayout);
            app.HighCutoffHzSpinnerLabel = uilabel(app.FilterPanelGridLayout);
            app.HighCutoffHzSpinnerLabel.Text = 'High Cutoff (Hz)';
            app.HighCutoffSpinner = uispinner(app.FilterPanelGridLayout);
            app.UpdateButton = uibutton(app.FilterPanelGridLayout, 'push');
            app.UpdateButton.Text = 'Update';
            app.StreamDropDownLabel = uilabel(app.GridLayout);
            app.StreamDropDownLabel.Text = 'Stream';
            app.StreamDropDown = uidropdown(app.GridLayout);
            app.StreamDropDown.Items = {'Filtered', 'Raw'};
            app.StreamDropDown.ItemsData = [0 1];
            app.StreamDropDown.ValueChangedFcn = createCallbackFcn(app, @StreamDropDownValueChanged, true);
            app.StreamDropDown.Value = 0;
            app.ApplyFilterCheckBox = uicheckbox(app.GridLayout);
            app.ApplyFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterCheckBoxValueChanged, true);
            app.ApplyFilterCheckBox.Enable = 'off';
            app.ApplyFilterCheckBox.Text = 'Apply Filter';
            app.StimulateandFetchButton = uibutton(app.GridLayout, 'push');
            app.StimulateandFetchButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateandFetchButtonPushed, true);
            app.StimulateandFetchButton.Enable = 'off';
            app.StimulateandFetchButton.Text = 'Stimulate and Fetch';
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.OptimizeButton = uibutton(app.GridLayout, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.CollectNaturalStimCheckBox = uicheckbox(app.GridLayout);
            app.CollectNaturalStimCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollectNaturalStimCheckBoxValueChanged, true);
            app.CollectNaturalStimCheckBox.Text = 'Collect Natural Stim';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Vibration', 'SCS'};
            app.DropDown.ItemsData = {'nat', 'scs'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'nat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OnlineOptimizerApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end