classdef opglx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPGLXUIFigure                  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ParametersMenu                 matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        ParametersMenu_2               matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        AllPlotsMenu                   matlab.ui.container.Menu
        CurrentPlotMenu                matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        ChooseUserDirectoryMenu        matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        PanelGridLayout                matlab.ui.container.GridLayout
        FetchLamp                      matlab.ui.control.Lamp
        AcquisitionTimeEditField       matlab.ui.control.EditField
        FetchTabGroup                  matlab.ui.container.TabGroup
        ContinuousTab                  matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        FetchButton                    matlab.ui.control.Button
        EventTab                       matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        StimulateandFetchButton        matlab.ui.control.Button
        PoststimLengthSpinner          matlab.ui.control.Spinner
        PoststimLengthsecLabel_2       matlab.ui.control.Label
        PoststimLengthBackground       matlab.ui.control.Label
        StimLengthSpinner              matlab.ui.control.Spinner
        StimLengthsecSpinnerLabel      matlab.ui.control.Label
        StimLengthBackground           matlab.ui.control.Label
        PrestimLengthSpinner           matlab.ui.control.Spinner
        PrestimLengthsecSpinnerLabel   matlab.ui.control.Label
        PrestimLengthBackground        matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        AutoScrollCheckBox             matlab.ui.control.CheckBox
        InitializeButton               matlab.ui.control.Button
        FetchParametersTabGroup        matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        StayBelowSpinner               matlab.ui.control.Spinner
        StayBelowsamplesLabel          matlab.ui.control.Label
        ArtifactFractionSpinner        matlab.ui.control.Spinner
        ArtifactFractionLabel          matlab.ui.control.Label
        ArtifactFractionBackground     matlab.ui.control.Label
        DetectionMethodDropDown        matlab.ui.control.DropDown
        DetectionMethodDropDownLabel   matlab.ui.control.Label
        DetectionMethodBackground      matlab.ui.control.Label
        ThresholdSpinner               matlab.ui.control.Spinner
        ThresholdSpinnerLabel          matlab.ui.control.Label
        StayBelowBackground            matlab.ui.control.Label
        ThresholdBackground            matlab.ui.control.Label
        EstimationMethodDropDown       matlab.ui.control.DropDown
        EstimationMethodDropDownLabel  matlab.ui.control.Label
        EstimationMethodBackground     matlab.ui.control.Label
        FractionSpinner                matlab.ui.control.Spinner
        FractionSpinnerLabel           matlab.ui.control.Label
        FetchFractionBackground        matlab.ui.control.Label
        WindowLengthSpinner            matlab.ui.control.Spinner
        WindowLengthsecLabel           matlab.ui.control.Label
        StreamDropDown                 matlab.ui.control.DropDown
        StreamDropDownLabel            matlab.ui.control.Label
        WindowLengthBackground         matlab.ui.control.Label
        StreamBackground               matlab.ui.control.Label
        MoreTab                        matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        ApplySmoothingCheckBox         matlab.ui.control.CheckBox
        GaussianMultiplierSpinner      matlab.ui.control.Spinner
        WindowLengthsecLabel_3         matlab.ui.control.Label
        GaussianMultiplierBackground   matlab.ui.control.Label
        SigmaSpinner                   matlab.ui.control.Spinner
        StayBelowsamplesLabel_2        matlab.ui.control.Label
        SigmaBackground                matlab.ui.control.Label
        BinSizeSpinner                 matlab.ui.control.Spinner
        WindowLengthsecLabel_2         matlab.ui.control.Label
        BinSizeBackground              matlab.ui.control.Label
        FiltersTab                     matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        ApplyFilterCheckBox            matlab.ui.control.CheckBox
        UpdateFilterButton             matlab.ui.control.Button
        OrderSpinner                   matlab.ui.control.Spinner
        OrderSpinnerLabel              matlab.ui.control.Label
        OrderBackground                matlab.ui.control.Label
        HighCutoffSpinner              matlab.ui.control.Spinner
        HighCutoffHzSpinnerLabel       matlab.ui.control.Label
        HighCutoffBackground           matlab.ui.control.Label
        LowCutoffSpinner               matlab.ui.control.Spinner
        LowCutoffHzSpinnerLabel        matlab.ui.control.Label
        LowCutoffBackground            matlab.ui.control.Label
        InfoTextArea                   matlab.ui.control.TextArea
        PlotTypeTabGroup               matlab.ui.container.TabGroup
        RasterTab                      matlab.ui.container.Tab
        RasterGridLayout               matlab.ui.container.GridLayout
        RasterAxes                     matlab.ui.control.UIAxes
        WaveformTab                    matlab.ui.container.Tab
        WaveformGridLayout             matlab.ui.container.GridLayout
        DisplayedChannelBSpinner       matlab.ui.control.Spinner
        DisplayedChannelBSpinnerLabel  matlab.ui.control.Label
        DisplayedChannelASpinner       matlab.ui.control.Spinner
        DisplayedChannelASpinnerLabel  matlab.ui.control.Label
        WaveformAxesB                  matlab.ui.control.UIAxes
        WaveformAxesA                  matlab.ui.control.UIAxes
        APStreamAxesB                  matlab.ui.control.UIAxes
        APStreamAxesA                  matlab.ui.control.UIAxes
        PCATab                         matlab.ui.container.Tab
        PCATabGridLayout               matlab.ui.container.GridLayout
        PC3Axes                        matlab.ui.control.UIAxes
        PC2Axes                        matlab.ui.control.UIAxes
        PC1Axes                        matlab.ui.control.UIAxes
        PCAAxes                        matlab.ui.control.UIAxes
        FiringRateTab                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        FiringRateAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %%
        paths

        thPool % thread pool used for processing data
        isAcquiring % flag for indicating if data is being fetched
        isStimulation
        hSGL % SpikeGLX client object

        fetcher % SpikeFetcher

        % s0 % sample count for acquiring data (neuropixel/imec stream)
        % s0_ni % sample count for acquiring data (NI stream, for aligning sycn waves)


        %fetchTimer % timer object for fetching data


        % F % future object for processing data with parfeval
        % data_uV % filtered AP stream data that is ready for processing, converted to microvolts (i.e. window_samples has been filled)
        % dataRaw_uV % native AP stream data that is read for processing, converted to microvolts

        % Each fetch call appends new filtered AP stream data to fetchData
        % Once fetchSampCnt >= window_samples, window_samples of data is
        % exctracted from fetchData and assigned to data_uV
        % fetchData
        % fetchSampCnt
        % 
        % fetchDataRaw % same as fetchData but for native AP stream
        % fetchRawSampCnt
        
        
        % fetchSyncDataNI
        % fetchSyncDataNP
        % fetchSyncCntNI
        % fetchSyncCntNP

        %% plots
        hRaster % raster plot
        hStimStartLine %
        hStimEndLine %
        
        WaveformTiledLayout
        hAPStreamA % AP stream voltage traces, channel A
        hAPStreamB % AP stream voltage traces, channel B
        hAPStreamThrA % AP stream threshold value, channel A
        hAPStreamThrB % AP stream threshold value, channel B
        hAPStreamSpikesA % scatter of locations on AP stream voltage traces of detected spikes, channel A
        hAPStreamSpikesB % scatter of locations on AP stream voltage traces of detected spikes, channel B
        APStreamYlims = struct("minVals", -300:50:-50, "maxVals", 50:50:300)
        
        hWaveformA % waveforms of detected spikes, channel A
        hWaveformB % waveforms of detected spikes, channel B

        hWaveErrA = struct("hPatch", [], "hEdge", [])
        hWaveErrB = struct("hPatch", [], "hEdge", [])

        hFiringRate

        hPCA
        hPC1
        hPC2
        hPC3
        pcaColors = struct("rgbVals", hex2rgb(["#b3e0ff","#1aa1ff"]), "map", [])

        plotType = 'rasterSpikes'; % indicates to detectSpikes and plotResult(app) which type of processing and plotting is needed
        saveCnt = 0;

        testFlag = 0;

        stimulator
        stimAndFetchEnabled = false

        currentResult
        
    end
    
    properties (Access = public)
        hParams % Description
    end

    
    methods (Access = private)
        
        function setDisplayedParameters(app)
            estimation_method_names = fieldnames(app.hParams.OP.estimationFcnList);
            app.EstimationMethodDropDown.Items = estimation_method_names;
            app.EstimationMethodDropDown.Value = app.hParams.OP.estimation_method;
            
            detection_method_names = fieldnames(app.hParams.OP.detectionFcnList);
            app.DetectionMethodDropDown.Items = detection_method_names;
            app.DetectionMethodDropDown.Value = app.hParams.OP.detection_method;

            app.ThresholdSpinner.Value = app.hParams.OP.threshold;
            app.BinSizeSpinner.Value = app.hParams.OP.bin_size * 1000;
            app.DisplayedChannelASpinner.Value = app.hParams.NP.plot_chans(1);
            app.DisplayedChannelBSpinner.Value = app.hParams.NP.plot_chans(2);
            app.APStreamAxesA.Title.String = num2str(app.hParams.NP.plot_chans(1));
            app.APStreamAxesB.Title.String = num2str(app.hParams.NP.plot_chans(2));
            app.PrestimLengthSpinner.Value = app.hParams.OP.prestim_len;
            app.StimLengthSpinner.Value = app.hParams.OP.stim_len;
            app.PoststimLengthSpinner.Value = app.hParams.OP.poststim_len;

            app.LowCutoffSpinner.Value = app.hParams.OP.filter.fcL;
            app.HighCutoffSpinner.Value = app.hParams.OP.filter.fcH;
            app.OrderSpinner.Value = app.hParams.OP.filter.n;
            app.ApplyFilterCheckBox.Value = app.hParams.OP.filter.apply;

            app.SigmaSpinner.Value = app.hParams.OP.smooth.sigma *1e3;
            app.GaussianMultiplierSpinner.Value = app.hParams.OP.smooth.mult;
            app.ApplySmoothingCheckBox.Value = app.hParams.OP.smooth.apply;


            %time_axes = get(app, )
            
            %xlim(app.RasterAxes, [-app.params.OP.prestim_len*1000 (app.params.OP.stim_len + app.params.OP.poststim_len)*1000])
        end

        function initializeTextAreas(app)
            %% initializes text areas that are updated with "updateDisplay"
            %dt = string(datetime('now', 'Format', 'yyy-MM-dd HH:mm:ss'));
            app.InfoTextArea.Value{end} = utils.formatMessage(opglx.constants.TOOLBOXNAME, 'Startup...');%sprintf('[%s %s] Startup.', class(app), dt);
        end
        
        function initializeAxes(app)
            app.updateTextArea(utils.formatMessage(opglx.constants.TOOLBOXNAME, 'Initializing Axes...'));
            time_xlims = [-app.hParams.OP.prestim_len*1000 (app.hParams.OP.stim_len + app.hParams.OP.poststim_len)*1000];
            % raster plot
            hold(app.RasterAxes, "on")
            app.hRaster = plot(app.RasterAxes, [0;0;NaN], 'Color', 'k');
            app.RasterAxes.XLim = time_xlims;
            app.RasterAxes.YLim = [min(app.hParams.NP.chans) - 0.5 max(app.hParams.NP.chans) + 0.5];
            app.RasterAxes.TickLength = [0,0];
            app.RasterAxes.YTick = app.hParams.NP.chan_ticks;
            app.RasterAxes.YTickLabel = app.hParams.NP.chan_tick_labels;
            app.hStimStartLine = xline(app.RasterAxes, 0, "Color", [0.39,0.83,0.07], "LineWidth", 2, "LineStyle", "--");
            app.hStimStartLine.Visible = "off";

            app.hStimEndLine = xline(app.RasterAxes, app.hParams.OP.stim_len*1000, "Color", [0.64,0.08,0.18], "LineWidth", 2, "LineStyle", '--');
            app.hStimEndLine.Visible = "off";

            % ap stream plot
            colorA = [0.07,0.62,1.00];
            colorB = [1.00,0.41,0.16];
            [app.APStreamAxesA.XLim,app.APStreamAxesB.XLim] = deal(time_xlims);
            [app.APStreamAxesA.YLim,app.APStreamAxesB.YLim] = deal([-150 150]);
            hold(app.APStreamAxesA, "on")
            hold(app.APStreamAxesB, "on")
            app.hAPStreamA = plot(app.APStreamAxesA, zeros(1, app.hParams.OP.window_samples), 'Color', colorA);
            app.hAPStreamB = plot(app.APStreamAxesB, zeros(1, app.hParams.OP.window_samples), 'Color', colorB);
            app.hAPStreamThrA = yline(app.APStreamAxesA, 0, "Color", "k", "LineWidth", 2, "LineStyle", "--");
            %app.hAPStreamThrA.Visible = "off";
            app.hAPStreamThrB = yline(app.APStreamAxesB, 0, "Color", "k", "LineWidth", 2, "LineStyle", "--");
            %app.hAPStreamThrB.Visible = "off";
            app.hAPStreamSpikesA = scatter(app.APStreamAxesA, [], [], 'r', 'filled');
            app.hAPStreamSpikesB = scatter(app.APStreamAxesB, [], [], 'r', 'filled');
            
            x = 1:app.hParams.OP.wv_samples;
            y = zeros(1, app.hParams.OP.wv_samples);
            
            hold([app.WaveformAxesA, app.WaveformAxesB], "on")
            app.hWaveformA = plot(app.WaveformAxesA, x, y, 'Color', [0.07,0.62,1.00]);
            app.hWaveformB = plot(app.WaveformAxesB, x, y, 'Color', [1.00,0.41,0.16]);
            [app.WaveformAxesA.XLim, app.WaveformAxesB.XLim] = deal([1 app.hParams.OP.wv_samples]);   
            
            ye = y + 1;
            errBar = repmat(ye, 2, 1);
            uE = y(:)' + errBar(1,:);
            lE = y(:)' - errBar(2,:);

            yP = [lE, fliplr(uE)];
            xP = [x, fliplr(x)];
            
            faceAlpha = 0.2;
            edgeColorA = colorA+(1-colorA)*0.55;
            edgeColorB = colorB+(1-colorB)*0.55;
            patchColorA = colorA;
            patchColorB = colorB;
            
            app.hWaveErrA.hPatch = patch(xP, yP, 1, "Parent", app.WaveformAxesA, "FaceColor", patchColorA, "EdgeColor", 'none', "FaceAlpha", faceAlpha);
            app.hWaveErrB.hPatch = patch(xP, yP, 1, "Parent", app.WaveformAxesB, "FaceColor", patchColorB, "EdgeColor", 'none', "FaceAlpha", faceAlpha);
            
            app.hWaveErrA.hEdge = plot(app.WaveformAxesA, [x NaN x], [lE NaN uE], '-', "Color", edgeColorA);
            %app.hWaveErrA.hEdges(2) = plot(app.WaveformAxesA, x, uE, '-', "Color", edgeColorA);
            
            app.hWaveErrB.hEdge = plot(app.WaveformAxesB, [x NaN x], [lE NaN uE], '-', "Color", edgeColorB);
            %app.hWaveErrB.hEdges(2) = plot(app.WaveformAxesB, x, uE, '-', "Color", edgeColorB);
            
            % firing rate
            %bc_temp = (app.hParams.OP.bin_size:app.hParams.OP.bin_size:app.hParams.OP.window_len) - app.hParams.OP.bin_size/2;
            app.hFiringRate = imagesc(app.FiringRateAxes, app.hParams.OP.bin_centers,  0:app.hParams.NP.num_chans-1, zeros(app.hParams.OP.max_bins, app.hParams.NP.num_chans));%plot(app.FiringRateAxes, app.hParams.OP.bin_centers, zeros(1, app.hParams.NP.num_chans));
            colormap(app.FiringRateAxes, "jet")
            set(app.FiringRateAxes, 'YDir', 'normal')
            xlabel(app.FiringRateAxes, 'Time (msec)')
            ylabel(app.FiringRateAxes, 'Channels')
            app.FiringRateAxes.XLim = [app.hParams.OP.bin_centers(1) app.hParams.OP.bin_centers(end)];%[1 app.hParams.OP.max_bins];%[app.params.OP.bin_centers(1) app.params.OP.bin_centers(end)];
            app.FiringRateAxes.YLim = [min(app.hParams.NP.chans) max(app.hParams.NP.chans)];
            app.FiringRateAxes.YTick = app.hParams.NP.chan_ticks;
            app.FiringRateAxes.YTickLabel = app.hParams.NP.chan_tick_labels;
            
            % pca
            hold(app.PCAAxes, "on")
            app.pcaColors.map = interp1(linspace(0,1,size(app.pcaColors.rgbVals,1)), app.pcaColors.rgbVals, linspace(0,1,256));
            colormap(app.PCAAxes, app.pcaColors.map);
            app.hPCA=patch(app.PCAAxes, [0:1:app.hParams.OP.max_bins-1 nan], [0:1:app.hParams.OP.max_bins-1 nan], [0:1:app.hParams.OP.max_bins-1 nan], [app.hParams.OP.bin_centers nan], "FaceColor", "none", "EdgeColor","interp", "LineWidth", 2);
            
            
            %app.hPCA = plot3(app.PCAAxes, 0,0,0,  'Color', 'k', 'LineWidth', 2);
           
            xlabel(app.PCAAxes, 'PC1')
            ylabel(app.PCAAxes, 'PC2')
            zlabel(app.PCAAxes, 'PC3')
            grid(app.PCAAxes, "on")
            axis(app.PCAAxes, "square")
            view(app.PCAAxes, 3);


            for i = 1:3
                colormap(app.(['PC' num2str(i) 'Axes']), app.pcaColors.map)
                app.(['hPC' num2str(i)]) = patch(app.(['PC' num2str(i) 'Axes']), [app.hParams.OP.bin_centers nan], [zeros(1, app.hParams.OP.max_bins) nan], [app.hParams.OP.bin_centers nan], "FaceColor", "none", "EdgeColor","interp", "LineWidth", 2);
                %app.(['hPC' num2str(i)]) = plot(app.(['PC' num2str(i) 'Axes']), app.hParams.OP.bin_centers, zeros(app.hParams.OP.max_bins), 'k', 'LineWidth', 2);
                app.(['PC' num2str(i) 'Axes']).XLim = time_xlims;
            end


        end
        
        function disableInitRequiredComponents(app)
            set(findall(app.EventTab, "-property", "Enable"), "Enable", "off")
            set(findall(app.ContinuousTab, "-property", "Enable"), "Enable", "off")
            set(findall(app.FetchParametersTabGroup, "-property", "Enable"), "Enable", "off")

        end
        function enableInitRequiredComponents(app)
            set(findall(app.ContinuousTab, "-property", "Enable"), "Enable", "on")
            set(findall(app.FetchParametersTabGroup, "-property", "Enable"), "Enable", "on")
            app.UpdateFilterButton.Enable = "off";
            if ~app.StreamDropDown.Value
                app.ApplyFilterCheckBox.Enable = "off";
            end
        end

%%
        function setupAcquisition(app)
            % initialize parallel pool
            % Using 'Threads' may not 
            % if isempty(gcp('nocreate'))
            %     app.InfoTextArea.Value = 'Starting parallel pool...';
            %     app.thPool = parpool('Threads');
            % else
            %     app.thPool = gcp('nocreate');
            % end

            %app.fetcher = acquisition.SpikeFetcher(app.hParams, app.thPool, @app.plotResult, @app.updateTimerDisplay, @app.updateTextArea, @app.fetchTimerErrorFcn);
            app.fetcher = acquisition.SpikeFetcher("parameters", app.hParams, ...
                "plotFcn", @app.plotResult, "displayInfoFcn", @app.updateTextArea, ...
                "timerDisplayFcn", @app.updateTimerDisplay, "fetchErrorFcn", @app.fetchTimerErrorFcn);
            addlistener(app.fetcher, "FetchStopped", @(src, event)app.stopAcquisition);
            assignin("base", "fetcher", app.fetcher)
        end


        function startAcquisition(app)
            %% start acquisition
            app.hStimStartLine.Visible = "off";
            app.hStimEndLine.Visible = "off";
            app.FetchButton.Text = 'Stop';
            msg = app.fetcher.start('Continuous');
            if strcmp(msg, 'ok')
                app.FetchLamp.Enable = "on";
            end
            app.WindowLengthSpinner.Limits = [0 3];


        end

        function stopAcquisition(app)
            %app.isAcquiring = false;
            app.FetchButton.Text = 'Fetch';
            app.fetcher.stop();
            app.FetchLamp.Enable = "off";
            max_len = size(app.fetcher.data_uV, 1) / round(app.hParams.NP.fs);
            app.WindowLengthSpinner.Limits = [0 min(max_len, 3)];
            %assignin("base", "fetcher", app.fetcher)
            %stop(app.dataTimer)
            %stop(app.fetchTimer)
            %Close(app.hSGL);
        end
        
        function startStimAndFetch(app)
            app.hStimStartLine.Visible = "off";
            app.hStimEndLine.Visible = "off";

            app.StimulateandFetchButton.Text = 'Acquiring...';
            msg = app.fetcher.start('Event');
            if strcmp(msg, 'ok')
                app.FetchLamp.Enable = "on";
            end

        end

        function endStimAndFetch(app, ~, ~)
            app.hStimStartLine.Visible = "on";
            app.hStimEndLine.Visible = "on";
            app.StimulateandFetchButton.Text = 'Stimulate and Fetch';
            app.FetchLamp.Enable = "off";

        end

        
        function plotResult(app, result)
            app.currentResult = result;
            
            %app.AcquisitionTimeEditField.Value = 'here2';
            %spike_times = result(:,1);%app.params.OP.time_ms(result(:,1));
            %spike_chans = result(:,2); %app.params.NP.chan_order(result(:,2)) - 1;
            %
            switch result{end}%app.plotType
                case 'rasterSpikes'
                    set(app.hRaster, "XData", result{1}, "YData", result{2})
                    drawnow %limitrate


                case 'getSpikeWaveforms'
                    chanA = app.hParams.NP.plot_chan_inds(1);
                    chanB = app.hParams.NP.plot_chan_inds(2);
                    % data = result{1};
                    % spike_times = result{2};
                    % spike_chans = result{3};
                    % spike_waveforms = results{4};
                    set(app.hAPStreamA, "XData", result{end-1}.OP.time_ms, "YData", result{1}(:, chanA))
                    % set(app.APStreamAxesA, "YLim", ...
                    %     [app.APStreamYlims.minVals(find(app.APStreamYlims.minVals<min(result{1}(:,chanA)), 1,"last")), ...
                    %     app.APStreamYlims.maxVals(find(app.APStreamYlims.maxVals>max(result{1}(:, chanA)), 1,"first"))])
                    set(app.hAPStreamB, "XData", result{end-1}.OP.time_ms, "YData", result{1}(:, chanB))
                    set(app.hAPStreamThrA, "Value", result{4}(chanA))
                    %fprintf('Channel: %d, Threshold: %0.4f\n', chanA, result{4}(chanA))
                    %result{4}(chanA)
                    set(app.hAPStreamThrB, "Value", result{4}(chanB))
                    % brutally unreadable if it works
                    set(app.hAPStreamSpikesA, "XData", result{end-1}.OP.time_ms(result{2}(result{3}==chanA)), ...
                        "YData", result{1}(result{2}(result{3}==chanA), chanA))
                    %assignin("base", "data", result{1})


                    set(app.hAPStreamSpikesB, "XData", result{end-1}.OP.time_ms(result{2}(result{3}==chanB)), ...
                        "YData", result{1}(result{2}(result{3}==chanB), chanB))
                    
                    
                    % set(app.hWaveformA, "XData", 1:app.hParams.OP.wv_samples, ...
                    %     "YData", mean(result{5}(:, result{3}==chanA), 2))
                    set(app.hWaveformA, "XData", 1:app.hParams.OP.wv_samples, ...
                        "YData", result{6}(:, chanA))
                    %set(app.hWaveformA, "Color", app.hParams.NP.cmap(chanA,:))

                    % set(app.hWaveformB, "XData", 1:app.hParams.OP.wv_samples, ...
                    %     "YData", mean(result{5}(:, result{3}==chanB), 2))
                    set(app.hWaveformB, "XData", 1:app.hParams.OP.wv_samples, ...
                        "YData", result{6}(:, chanB))
                    %set(app.hWaveformB, "Color", app.hParams.NP.cmap(chanB,:))

                    [yP_A, lE_A, uE_A] = plotting.calculateErrorPatchValues(result{6}(:, chanA), result{7}(:, chanA));
                    [yP_B, lE_B, uE_B] = plotting.calculateErrorPatchValues(result{6}(:, chanB), result{7}(:, chanB));

                    set(app.hWaveErrA.hPatch, "YData", yP_A)
                    set(app.hWaveErrB.hPatch, "YData", yP_B)
                    
                    set(app.hWaveErrA.hEdge, "YData", [lE_A NaN uE_A])
                    set(app.hWaveErrB.hEdge, "YData", [lE_B NaN uE_B])
                    %set(app.hWaveErrA.hEdges(1), "YData", lE_A)
                    %set(app.hWaveErrA.hEdges(2), "YData", uE_A)

                    %set(app.hWaveErrB.hEdges(1), "YData", lE_B)
                    %set(app.hWaveErrB.hEdges(2), "YData", uE_B)

                    drawnow
                
                case 'getSpikeFiringRates'
                    %loc=find(app.hParams.OP.bin_centers==min(abs(app.hParams.OP.bin_centers)))-1;
                    %baseline = result{4}(1:loc,:);
                    set(app.hFiringRate, "XData", result{end-1}.OP.bin_centers, "CData", result{4}')
                    %contourf(app.FiringRateAxes, result{4}(:,app.hParams.NP.chans+1)')
                    %imagesc(app.FiringRateAxes, )
                    %colormap(jet(6))
                    %xline(app.FiringRateAxes, find(app.hParams.OP.bin_centers==min(abs(app.hParams.OP.bin_centers))), "Color", [0.39,0.83,0.07], "LineWidth", 2, "LineStyle", "--")
                    %assignin("base", "hIM", app.FiringRateAxes.Children)
                    %set(app.hFiringRate, "XData", app.hParams.OP.bin_centers)
            
                case 'pcaSpikes'
                    %score = smooth(result{5});
                    pc1 = smooth(result{5}(:,1))';
                    pc2 = smooth(result{5}(:,2))';
                    pc3 = smooth(result{5}(:,3))';
                    
                    set(app.hPCA, "XData", [pc1 nan], "YData", [pc2 nan], "ZData", [pc3 nan], "CData", [result{end-1}.OP.bin_centers nan])
                    set(app.hPC1, "YData", [pc1 nan])%, "XData", [result{end-1}.OP.bin_centers nan])
                    set(app.hPC2, "YData", [pc2 nan])%, "XData", [result{end-1}.OP.bin_centers nan])
                    set(app.hPC3, "YData", [pc3 nan])%, "XData", [result{end-1}.OP.bin_centers nan])
                    set([app.hPC1 app.hPC2 app.hPC3], "XData", [result{end-1}.OP.bin_centers nan], "CData", [result{end-1}.OP.bin_centers nan])
                    %set([app.hPC1, app.hPC2, app.hPC3], "XData", app.hParams.OP.bin_centers)
                    %set([app.hPC1, app.hPC2, app.hPC3], "XData", app.hParams.OP.bin_centers)
                    %start_loc=find(app.hParams.p.OP.bin_centers==min(abs(app.hParams.p.OP.bin_centers)))-1;
                    %end_loc=find(app.hParams.p.OP.bin_edges == app.hParams.p.OP.stim_len*10^3);
                    %app.hParams.p.OP.bin_edges(start_loc)
                    %app.hParams.p.OP.bin_edges(end_loc)
                    
                    %hold(app.PCAAxes, "off")
                    %app.hPCA = plot3(app.PCAAxes, pc1, pc2, pc3);
                    %hold(app.PCAAxes, "on")

                    %plot3(app.PCAAxes, pc1(1), pc2(1), pc3(1), 'ko')
                    %plot3(app.PCAAxes, pc1(start_loc), pc2(start_loc), pc3(start_loc), 'go')
                    %plot3(app.PCAAxes, pc1(end_loc), pc2(end_loc), pc3(end_loc), 'ro')
                    %annotation(app.PCAAxes, 'arrow', [pc1(end),pc2(end), pc3(end)]);
                    % if app.isCollecting
                    %     app.collectedEvents = app.collectedEvents + 1;
                    %     cnt = app.collectedEvents;
                    %     app.naturalResponse(cnt).data_uV = result{1};
                    %     app.naturalResponse(cnt).spike_times = result{2};
                    %     app.naturalResponse(cnt).spike_chans = result{3};
                    %     app.naturalResponse(cnt).firing_rate = result{4};
                    %     app.naturalResponse(cnt).score = result{5};
                    %     firing_rate_avg = mean(cat(3, app.naturalResponse.firing_rate), 3);
                    %     [~, app.naturalResponse(cnt).cumulative_score, ~, ~, ~] = pca(firing_rate_avg);
                    %     app.naturalScore = app.naturalResponse(end).cumulative_score;
                    % end
                    drawnow

                otherwise
                    return
            end


        end


        % function updateWaveform(app)
        %     %app.F = parfeval(app.thPool, @detectSpikes, 1, app.data_uV, app.hParams, app.plotType);
        %     %afterEach(app.F, @app.plotResult, 0);
        % 
        % end
        

        function setupStimulator(app)
            dispFcn = @(msg)app.updateTextArea(msg);
            addlistener(app.stimulator, "StatusMessage", "PostSet", @(src, event)stim.ArduinoStimulator.displayStatusMessage(dispFcn, src, event));
            app.stimulator.initialize();
            %app.stimulator.attachListener();
            
            addlistener(app.stimulator, "InitializationSuccess", @app.enableStimAndFetch);
            
            
        end

        function enableStimAndFetch(app, ~, ~)
            addlistener(app.fetcher, "DeliverStimulus", @(src, event)app.stimulator.deliverStimulus(event));
            addlistener(app.fetcher, "EventFetched", @app.endStimAndFetch);
            addlistener(app.fetcher, "EventNotFound", @app.endStimAndFetch);
            %app.StimulateandFetchButton.Enable = "on"; 
            app.stimAndFetchEnabled = true;
            set(findall(app.EventTab, "-property", "Enable"), "Enable", "on")
        end

        function enableOptimization(app, ~, ~)
            
        end


    end
    
    methods (Access = public)
        
        function updateFetchLengthDisplay(app, type)
            param_len_name = [type '_len'];
            %param_samples_name = [type '_samples'];
            component = findobj(app.FetchLengthPanel, "Tag", type);
            component.Value = app.hParams.OP.(param_len_name);
            if strcmp(type, "stim")
                app.hStimEndLine.Value = app.hParams.OP.(param_len_name) * 1000;
            end
            [app.RasterAxes.XLim, app.APStreamAxesA.XLim, app.APStreamAxesB.XLim] = deal([-app.hParams.OP.prestim_len*1000 (app.hParams.OP.stim_len + app.hParams.OP.poststim_len)*1000]);
        end

        function fetchTimerErrorFcn(app, obj, event)
            assignin("base", "obj", obj)
            assignin("base", "event", event)
            spikeFetcher = app.fetcher;
            assignin("base", "spikeFetcher", spikeFetcher)
            app.InfoTextArea.Value = [app.InfoTextArea.Value; event.Data.message];
            if app.AutoScrollCheckBox.Value
                scroll(app.InfoTextArea, "bottom")
            end
            % data_raw = app.fetcher.dataRaw_uV;
            % data_fetch = app.fetcher.fetchDataRaw;
            % param = app.hParams;
            % assignin("base", "data_raw", data_raw)
            % assignin("base", "data_fetch", data_fetch)
            % assignin("base", "param", param)

            if app.isStimulation
                %data_sy = app.fetchSyncDataNP(1:app.hParams.NP.sync_samples,:);
                %data_ni = app.fetchSyncDataNI(1:app.hParams.NI.sync_samples,:);
                %assignin("base", "data_sy", data_sy)
                %assignin("base", "data_ni", data_ni)
                endStimAndFetch(app)
            else
                stopAcquisition(app)
            end

        end

        function updateTimerDisplay(app, t)
            t = seconds(t);
            t.Format = 'mm:ss';%'mm:ss.S';%.SSS';
            app.AcquisitionTimeEditField.Value = string(t);
        end

        function updateTextArea(app, msg)
            msg = char(msg);
            app.InfoTextArea.Value = [app.InfoTextArea.Value; msg];
            if app.AutoScrollCheckBox.Value
                scroll(app.InfoTextArea, "bottom")
            end

        end

        function updateDisplay(app, targetTextArea, msg)
            %% updates text area with "msg" specified by "targetTextArea"
            app.(targetTextArea).Value{end+1} = msg;
            scroll(app.(targetTextArea), 'bottom')
            if app.AutoScrollCheckBox.Value
                scroll(app.InfoTextArea, "bottom")
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, opts)
            arguments
                app (1,1) opglx
                opts.params
                opts.stimulator

            end
            app.OPGLXUIFigure.Theme = 'light';
            %set(findall(app.EventTab, "-property", "Enable"), "Enable", "off")
            disableInitRequiredComponents(app)
            % set(app.FetchParametersTabGroup, "FontName", 'Courier')
            % set(app.PlotTypeTabGroup, "FontName", 'Courier')
            % set(app.FetchTabGroup, "FontName", 'Courier')
            % assignin('base', 'f1', fields(app.FetchTabGroup))
            % assignin('base', 'f2', fields(app.ContinuousTab))

            initializeTextAreas(app)
            app.paths = opglx.initialize("displayFcn", @(msg)app.updateTextArea(msg));
            
            if isfield(opts, "params")
                app.hParams = opts.params;
                
            else
                app.hParams = acquisition.ParameterManager;

            end
            if isfield(opts, "stimulator")

                app.StimLengthSpinner.Enable = "off";
                app.StimulateandFetchButton.Enable = "on";
            else
                app.stimulator = stim.ArduinoStimulator;
                app.stimulator.findPorts;
            end
            %parameters = app.hParams;
            %assignin("base", "params", parameters)
            
            setDisplayedParameters(app)
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            %app.FetchButton.Enable = "off";
            %fetchData(app)
            %app.OPGLXUIFigure.Position = [178, 122, 1000, 750];
            %app.OPGLXUIFigure.Position
            
            if ~app.fetcher.isAcquiring
                startAcquisition(app)
            else
                stopAcquisition(app)
                %assignin('base', 'd', app.fetcher.data_uV)
            end
        end

        % Callback function
        function TestButtonPushed(app, event)
            % app.fetchCallsCnt = 0;
            % % app.fetchSampCnt = 0;
            % % 
            % start_samples = app.startSamps;
            % fetched_samples = app.fetchedSamps;
            % assignin("base", "fetched_samples", fetched_samples)
            % assignin("base", "start_samples", start_samples)
            % f = app.F;
            % assignin("base", "F", f)
            % app.fetchTimer.Period
            % data = app.data_uV;
            % dataraw = app.dataRaw_uV;
            % param = app.params;
            % assignin("base", "params", param)
            % assignin("base", "data_uV", data)
            % assignin("base", "dataraw", dataraw)
            % fd = app.fetchData;
            % assingin("base", "fd", fd)
            % ax = app.RasterAxes;
            % assignin("base", "ax", ax)
            % th = app.thPool;
            % assignin("base", "th", th)
            % dt = app.dataTimer;
            % assignin("base", "dt", dt)
            %app.params.NP.plot_chan_inds
            % detectSpikes(app.data_uV, app.params)
            %app.params.NI.sync_samples
            % nr= app.naturalResponse;
            % assignin("base", "nr", nr)
            %endOptimization(app)
            %app.hParams.OP.window_samples
            % optimizationData = struct('naturalResponse', app.naturalResponse, 'collectedEvents', app.collectedEvents, 'naturalScore', app.naturalScore, 'optimizationResults', app.optimizationResults, ...
            %     'params', app.params);
            % save("E:\SGL_DATA\rec_4-8-25\optimizationData.mat", "optimizationData", '-mat')
            % app.params.NI.stim_word
            % app.InfoTextArea.Value = [app.InfoTextArea.Value; 'memede'];
            % scroll(app.InfoTextArea, "bottom")
            f = app.fetcher;
            assignin("base", "fetcher", f)
        end

        % Value changed function: EstimationMethodDropDown
        function EstimationMethodDropDownValueChanged(app, event)
            value = app.EstimationMethodDropDown.Value;
            app.hParams.OP.estimation_method = value;
            app.hParams.OP.estimationFcn = app.hParams.OP.estimationFcnList.(value);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            value = app.DetectionMethodDropDown.Value;
            app.hParams.updateDetectionMethod(value);
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring 
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            value = app.ThresholdSpinner.Value;
            %app.hParams.OP.threshold = value;
            app.hParams.updateDetectionParameter('threshold', value);
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring %%&& strcmp(app.PlotTypeTabGroup.SelectedTab.Tag, 'rasterSpikes')
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: StayBelowSpinner
        function StayBelowSpinnerValueChanged(app, event)
            value = app.StayBelowSpinner.Value;
            %app.hParams.OP.stay_below_cnt = value;
            app.hParams.updateDetectionParameter('stay_below_cnt', value)

            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring 
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: ArtifactFractionSpinner
        function ArtifactFractionSpinnerValueChanged(app, event)
            value = app.ArtifactFractionSpinner.Value;
            app.hParams.updateDetectionParameter('artifact_percent', value)
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring 
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: DisplayedChannelASpinner, 
        % ...and 1 other component
        function DisplayedChannelSpinnerValueChanged(app, event)
            %value = app.DisplayedChannelASpinner.Value;
            value = event.Source.Value;
            
            %str2double(event.Source.Tag);
            val_loc = logical(str2double(split(event.Source.Tag))'); %this is dumb, set tags to '0 1' and '1 0'
            value_p = app.hParams.NP.plot_chans(~val_loc);
            if value == value_p
                prev_value = event.PreviousValue;
                if value == app.hParams.NP.num_chans - 1 || value == 0
                    value = prev_value;

                elseif prev_value < value
                    value = value + 1;
                else
                    value = value - 1;
                end
                event.Source.Value = value;
            end
            axis_names = {'A', 'B'};
            app.(['APStreamAxes' axis_names{val_loc}]).Title.String = num2str(value);
            app.hParams.NP.plot_chans(val_loc) = value;
            app.hParams.NP.plot_chan_inds(val_loc) = value + 1;
            % if ~isempty(app.experimentApp)
            %     app.experimentApp.acquisitionParams = app.hParams;
            % end
            % if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring && strcmp(app.PlotTypeTabGroup.SelectedTab.Tag, 'waveform')
            %     updateWaveform(app)
            % end
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring
                if strcmp(app.currentResult{end}, app.plotType)
                    app.plotResult(app.currentResult)
                else
                    app.fetcher.processCurrentData();
                end
            end
            % app.params.NP.plot_chans
            % app.params.NP.plot_chan_inds
        end

        % Value changed function: BinSizeSpinner
        function BinSizeSpinnerValueChanged(app, event)
            value = app.BinSizeSpinner.Value;
            app.hParams.updateBinSize(value*10^-3)
            % set(app.hFiringRate, "XData", app.hParams.OP.bin_centers)
            % set([app.hPC1, app.hPC2, app.hPC3], "XData", app.hParams.OP.bin_centers)

            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring 
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: WindowLengthSpinner
        function WindowLengthSpinnerValueChanged(app, event)
            value = app.WindowLengthSpinner.Value;%event.Source.Value;
            app.hParams.updateWindowLength(value);
            [app.RasterAxes.XLim, app.APStreamAxesA.XLim, app.APStreamAxesB.XLim] = deal([0 app.hParams.OP.window_len*1000]);
            [app.PC1Axes.XLim, app.PC2Axes.XLim, app.PC3Axes.XLim] = deal([0 app.hParams.OP.window_len*1000]);
            
            app.PrestimLengthSpinner.Value = 0;
            app.StimLengthSpinner.Value = value;
            app.PoststimLengthSpinner.Value = 0;
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring 
                app.fetcher.processCurrentData();
            end
            if app.hParams.initialized && app.hParams.OP.max_bins > 1
                
                app.FiringRateAxes.XLim = [0 app.hParams.OP.window_len*1000];%[app.hParams.OP.bin_centers(1) app.hParams.OP.bin_centers(end)];%[1 app.hParams.OP.max_bins];
            end


        end

        % Value changed function: PoststimLengthSpinner, 
        % ...and 2 other components
        function EventLengthSpinnerValueChanged(app, event)
            value = event.Source.Value;
            val_tag = event.Source.Tag;
            app.hParams.updateEventLength(val_tag, value);
            app.WindowLengthSpinner.Value = app.hParams.OP.window_len;
            [app.RasterAxes.XLim, app.APStreamAxesA.XLim, app.APStreamAxesB.XLim] = deal([-app.hParams.OP.prestim_len*1000 (app.hParams.OP.stim_len + app.hParams.OP.poststim_len)*1000]);
            if app.hParams.initialized && app.hParams.OP.max_bins > 1
                app.FiringRateAxes.XLim = [1 app.hParams.OP.max_bins];
            end
        end

        % Selection change function: PlotTypeTabGroup
        function PlotTypeTabGroupSelectionChanged(app, event)
            selectedTab = app.PlotTypeTabGroup.SelectedTab;
            app.plotType = selectedTab.Tag;
            app.hParams.OP.plotType = selectedTab.Tag;
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: StreamDropDown
        function StreamDropDownValueChanged(app, event)
            value = app.StreamDropDown.Value;
            if value
                app.hParams.NP.js_filtered = value;
                app.ApplyFilterCheckBox.Enable = "on";
            else
                app.hParams.NP.js_filtered = -1;
                app.hParams.OP.filter.apply = false;
                app.ApplyFilterCheckBox.Enable = "off";
                app.ApplyFilterCheckBox.Value = 0;
                
                %app.ApplyFilterCheckBox.Value = 0;
            end
            % if ~app.fetcher.isAcquiring
            %     if value
            %         data = app.dataRaw_uV;
            %     else
            %         data = app.data_uV;
            %     end
            %     app.F = parfeval(app.thPool, @spikes.detectSpikes, 1, data, app.hParams);
            %     afterEach(app.F, @app.plotResult, 0);
            % end
        end

        % Value changed function: FractionSpinner
        function FractionSpinnerValueChanged(app, event)
            value = app.FractionSpinner.Value;
            app.hParams.OP.fetch_fraction = value;
        end

        % Value changed function: ApplyFilterCheckBox
        function ApplyFilterCheckBoxValueChanged(app, event)
            value = app.ApplyFilterCheckBox.Value;
            if value
                app.hParams.OP.filter.zf = [];
            end
            app.hParams.OP.filter.apply = value;
            %app.FilterPanel.Enable = value;
            %app.params.OP.apply_filter
            if ~app.fetcher.isAcquiring
                %app.F = parfeval(app.thPool, @detectSpikes, 1, app.dataRaw_uV, app.hParams, 'raster');
                %afterEach(app.F, @app.plotResult, 0);
            end
        end

        % Value changed function: ApplySmoothingCheckBox
        function ApplySmoothingCheckBoxValueChanged(app, event)
            value = app.ApplySmoothingCheckBox.Value;
            app.hParams.OP.smooth.apply = value;
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: SigmaSpinner
        function SigmaSpinnerValueChanged(app, event)
            value = app.SigmaSpinner.Value * 1e-3;
            app.hParams.updateGaussianKernel("sigma", value);
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring
                app.fetcher.processCurrentData();
            end
        end

        % Value changed function: GaussianMultiplierSpinner
        function GaussianMultiplierSpinnerValueChanged(app, event)
            value = app.GaussianMultiplierSpinner.Value;
            app.hParams.updateGaussianKernel("mult", value);
            if ~isempty(app.currentResult) && ~app.fetcher.isAcquiring 
                app.fetcher.processCurrentData();
            end
        end

        % Button pushed function: StimulateandFetchButton
        function StimulateandFetchButtonPushed(app, event)
            startStimAndFetch(app)
        end

        % Callback function
        function CollectNaturalStimCheckBoxValueChanged(app, event)
            % selects whether next stimulate and fetch will add natural
            % stim data to
            value = app.CollectNaturalStimCheckBox.Value;
            app.isCollecting = value;
            if value && ~strcmp(app.hParams.OP.stim_type, 'nat')
                app.hParams.OP.stim_type = 'nat';
                app.hParams.NI.stim_word = app.hParams.NI.nat_stim_word;
            end

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'nat'
                    app.hParams.OP.stim_type = 'nat';
                    app.hParams.NI.stim_word = app.hParams.NI.nat_stim_word;
                case 'scs'
                    app.hParams.OP.stim_type = 'scs';
                    app.hParams.NI.stim_word = app.hParams.NI.scs_stim_word;
            end
app.hParams.NI.stim_word
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %[app.hParams, msg] = acquisition.initializeParameters(app.hParams);
            app.InitializeButton.Enable = "off";
            drawnow;
            msg = initialize(app.hParams);
            if app.hParams.initialized
                %app.InitializeButton.Enable = "off";
                initializeAxes(app)
                setupAcquisition(app)
                setupStimulator(app)
                enableInitRequiredComponents(app)
                app.InitializeButton.Text = "Initialized";
            else
                app.InitializeButton.Enable = "on";
            end
            %app.InfoTextArea.Value = msg;
            app.updateTextArea(utils.formatMessage(opglx.constants.TOOLBOXNAME, msg))

        end

        % Callback function
        function FetchTabGroupSelectionChanged(app, event)
            %selectedTab = app.FetchTabGroup.SelectedTab;
            %set(app.EventTab.Children(isprop(app.EventTab.Children, "Enable")), "Enable", "off")
            %findobj(app.EventTab, "-proptery", "Enable")
            %set(findall(app.EventTab, "-property", "Enable"), "Enable", "off")
            %isprop([app.EventTab.Children], "Enable")
            % if strcmp(selectedTab.Title, "Event") && ~app.stimAndFetchEnabled
            %     app.FetchTabGroup.SelectedTab = app.ContinuousTab;
            % 
            % end
        end

        % Close request function: OPGLXUIFigure
        function OPGLXUIFigureCloseRequest(app, event)
            if ~isempty(app.fetcher) 
                if app.fetcher.isAcquiring
                    stopAcquisition(app)

                end
                app.fetcher.delete();
            end
            % Clean up timers
            % if ~isempty(app.dataTimer) && isvalid(app.dataTimer)
            %     stop(app.dataTimer);
            %     delete(app.dataTimer);
            % end
            % if ~isempty(app.fetchTimer) && isvalid(app.fetchTimer)
            %     stop(app.fetchTimer);
            %     delete(app.fetchTimer);
            % end
            % Delete background pool
            % if ~isempty(app.bgPool)
            %     delete(app.bgPool);
            % end
            if ~isempty(app.thPool)
                delete(app.thPool)
            end

            delete(app)
            
        end

        % Value changed function: HighCutoffSpinner, LowCutoffSpinner, 
        % ...and 1 other component
        function FilterParameterSpinnerValueChanged(app, event)
            tag = event.Source.Tag;
            value = app.OrderSpinner.Value;
            if ~isequal(value, app.hParams.OP.filter.(tag))
                app.UpdateFilterButton.Enable = "on";
            end
            %
            
        end

        % Button pushed function: UpdateFilterButton
        function UpdateFilterButtonPushed(app, event)
            fcL = app.LowCutoffSpinner.Value;
            fcH = app.HighCutoffSpinner.Value;
            n = app.OrderSpinner.Value;
            app.hParams.updateFilter("fcL", fcL, "fcH", fcH, "n", n)
            app.UpdateFilterButton.Enable = "off";

        end

        % Menu selected function: AllPlotsMenu
        function AllPlotsMenuSelected(app, event)
            user_paths = opglx.getUserPaths();
            dt = " " + string(datetime('now', 'Format', 'yyyy-MM-dd HHmmss')) + ".png";
            w = warning('off', 'MATLAB:print:ExportappForUIFigureWithUIControl');
            for plotTab = string({app.PlotTypeTabGroup.Children.Title})
                fname = fullfile(user_paths.figures, plotTab+dt);
                switch plotTab
                    case "Raster"
                        exportgraphics(app.RasterAxes, fname, "Resolution", 300);

                    case "Waveform"
                        tempFig = uifigure("Visible", "off");
                        copyobj(app.WaveformTab.Children, tempFig);

                        set(tempFig.Children, "BackgroundColor", [1 1 1])
                        posTemp = tempFig.Position;
                        posTemp(3:4) = app.WaveformTab.Position(3:4);
                        set(tempFig, "Position", posTemp)
                        drawnow
                        pause(0.5)

                        exportgraphics(tempFig, fname, "Resolution", 300);
                        close(tempFig)

                    case "PCA"
                        tempFig = uifigure('Visible', 'off');
                        copyobj(app.PCATab.Children, tempFig);

                        set(tempFig.Children, "BackgroundColor", [1 1 1])
                        posTemp = tempFig.Position;
                        posTemp(3:4) = app.PCATab.Position(3:4);
                        set(tempFig, "Position", posTemp)
                        
                        drawnow
                        pause(0.5)

                        exportgraphics(tempFig, fname, "Resolution", 300);
                        close(tempFig)

                    case "Firing Rate"
                        exportgraphics(app.FiringRateAxes, fname, "Resolution", 300)

                end
                app.updateTextArea(utils.formatMessage(opglx.constants.TOOLBOXNAME, plotTab + " plot saved!"))
            end
            warning(w)
            

        end

        % Menu selected function: CurrentPlotMenu
        function CurrentPlotMenuSelected(app, event)
            user_paths = opglx.getUserPaths();
            currentTab = string(app.PlotTypeTabGroup.SelectedTab.Title);
            fname = fullfile(user_paths.figures, currentTab + " " + string(datetime('now', 'Format', 'yyyy-MM-dd HHmmss')));
            app.CurrentPlotMenu.Enable = "off";
            switch currentTab
                case "Raster"
                    exportgraphics(app.RasterAxes, fname+".eps", "ContentType","vector", "Padding", "figure")
                    %exportgraphics(app.RasterAxes, fname+".png", "Resolution", 300)
                case "Waveform"
                    tempFig = uifigure("Visible", "off");%('Visible', 'off');
                    copyobj(app.WaveformTab.Children, tempFig);
                    % spinners = findobj(gridTemp, "Type", "uispinner");
                    % labels = findobj(gridTemp, "Type", "uilabel");
                    % delete(spinners)
                    % delete(labels)
                    %tempFig.Children.BackgroundColor = [1 1 1];
                    set(tempFig.Children, "BackgroundColor", [1 1 1])
                    posTemp = tempFig.Position;
                    posTemp(3:4) = app.WaveformTab.Position(3:4);
                    
                    %tempFig.Position(3:4) = app.WaveformTab.Position(3:4);
                    set(tempFig, "Position", posTemp)
                    %set(tempFig, "Renderer", "painters")
                    
                    drawnow
                    pause(0.5) % unforntunately, can't seem to get it to export properly without the pause
                    % pretty sure exportgraphics executes before everything
                    % is rentered in tempFig and results in some or none of
                    % the axes being present in the png, only white space
     
                    
                    %exportapp(tempFig, fname+".png")
                    % temporarily suppress warning message about not being
                    % able to export UI components. can use exportapp
                    % instead but it doesn't allow for any Name-Value
                    % settings like Resolution to be set.
                    w = warning('off', 'MATLAB:print:ExportappForUIFigureWithUIControl');
                    %exportgraphics(tempFig, fname+".png", "Resolution", 300);
                    exportgraphics(tempFig, fname+".eps", "ContentType","vector", "Padding", "figure")
                    warning(w)
                    
                    close(tempFig)
                case "PCA"
                    tempFig = uifigure('Visible', 'off');%("Color", [1 1 1]);%;
                    copyobj(app.PCATab.Children, tempFig);

                    set(tempFig.Children, "BackgroundColor", [1 1 1])
                    posTemp = tempFig.Position;
                    posTemp(3:4) = app.PCATab.Position(3:4);
                    set(tempFig, "Position", posTemp)

                    drawnow
                    pause(0.5)
                    %exportapp(tempFig, fname+".png");
                    w = warning('off', 'MATLAB:print:ExportappForUIFigureWithUIControl');
                    exportgraphics(tempFig, fname+".png", "Resolution", 300);
                    %exportgraphics(tempFig, fname+".eps", "ContentType","vector")
                    warning(w)
                    
                    close(tempFig)

                case "Firing Rate"
                    exportgraphics(app.FiringRateAxes, fname+".png", "Resolution", 300)
                    %exportgraphics(app.FiringRateAxes, fname+".eps", "ContentType","vector")

            end
            app.CurrentPlotMenu.Enable = "on";
            app.updateTextArea(utils.formatMessage(opglx.constants.TOOLBOXNAME, currentTab + " plot saved!"))
            %exportgraphics(app.RasterAxes, fullfile('myplot.png', 'Resolution', 300);
            %uiexportdlg(app.OPGLXUIFigure)
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)
            
        end

        % Menu selected function: ChooseUserDirectoryMenu
        function ChooseUserDirectoryMenuSelected(app, event)
            old_paths = opglx.getUserPaths();
            
            f = figure('Position', [-100 -100 0 0]);
            selpath = uigetdir(old_paths.root, 'Select New Directory');
            delete(f);
            if selpath == 0
                uialert(app.OPGLXUIFigure, 'Diretory Selection Canceled', 'Canceled', 'Icon', 'info');

            else
                
                opglx.setUserRoot(selpath, "displayFcn", @(msg)app.updateTextArea(msg))

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPGLXUIFigure = uifigure('Visible', 'off');
            app.OPGLXUIFigure.Name = 'OP-GLX';
            app.OPGLXUIFigure.Icon = 'OP-GLX Icon.png';
            app.OPGLXUIFigure.CloseRequestFcn = createCallbackFcn(app, @OPGLXUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.OPGLXUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.ParametersMenu = uimenu(app.SaveMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.ParametersMenu_2 = uimenu(app.LoadMenu);
            app.ParametersMenu_2.Text = 'Parameters';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.AllPlotsMenu = uimenu(app.ExportMenu);
            app.AllPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @AllPlotsMenuSelected, true);
            app.AllPlotsMenu.Text = 'All Plots';
            app.CurrentPlotMenu = uimenu(app.ExportMenu);
            app.CurrentPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentPlotMenuSelected, true);
            app.CurrentPlotMenu.Text = 'Current Plot';
            app.OptionsMenu = uimenu(app.OPGLXUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ChooseUserDirectoryMenu = uimenu(app.OptionsMenu);
            app.ChooseUserDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ChooseUserDirectoryMenuSelected, true);
            app.ChooseUserDirectoryMenu.Text = 'Choose User Directory';
            app.GridLayout = uigridlayout(app.OPGLXUIFigure);
            app.PlotTypeTabGroup = uitabgroup(app.GridLayout);
            app.PlotTypeTabGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeTabGroupSelectionChanged, true);
            app.RasterTab = uitab(app.PlotTypeTabGroup);
            app.RasterTab.Title = 'Raster';
            app.RasterTab.Tag = 'rasterSpikes';
            app.RasterGridLayout = uigridlayout(app.RasterTab);
            app.RasterAxes = uiaxes(app.RasterGridLayout);
            title(app.RasterAxes, 'Raster')
            xlabel(app.RasterAxes, 'Time (msec)')
            ylabel(app.RasterAxes, 'Channels')
            zlabel(app.RasterAxes, 'Z')
            app.WaveformTab = uitab(app.PlotTypeTabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.WaveformTab.Tag = 'getSpikeWaveforms';
            app.WaveformGridLayout = uigridlayout(app.WaveformTab);
            app.APStreamAxesA = uiaxes(app.WaveformGridLayout);
            title(app.APStreamAxesA, 'Channel A')
            xlabel(app.APStreamAxesA, 'Time (msec)')
            ylabel(app.APStreamAxesA, 'Voltage (μV)')
            zlabel(app.APStreamAxesA, 'Z')
            app.APStreamAxesB = uiaxes(app.WaveformGridLayout);
            title(app.APStreamAxesB, 'Channel B')
            xlabel(app.APStreamAxesB, 'Time (msec)')
            ylabel(app.APStreamAxesB, 'Voltage (μV)')
            zlabel(app.APStreamAxesB, 'Z')
            app.WaveformAxesA = uiaxes(app.WaveformGridLayout);
            xlabel(app.WaveformAxesA, 'Samples')
            ylabel(app.WaveformAxesA, 'Voltage (μV)')
            zlabel(app.WaveformAxesA, 'Z')
            app.WaveformAxesB = uiaxes(app.WaveformGridLayout);
            xlabel(app.WaveformAxesB, 'Samples')
            ylabel(app.WaveformAxesB, 'Voltage (μV)')
            zlabel(app.WaveformAxesB, 'Z')
            app.DisplayedChannelASpinnerLabel = uilabel(app.WaveformGridLayout);
            app.DisplayedChannelASpinnerLabel.Text = 'Displayed Channel A';
            app.DisplayedChannelASpinner = uispinner(app.WaveformGridLayout);
            app.DisplayedChannelASpinner.Limits = [0 383];
            app.DisplayedChannelASpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayedChannelSpinnerValueChanged, true);
            app.DisplayedChannelASpinner.Tag = '1 0';
            app.DisplayedChannelBSpinnerLabel = uilabel(app.WaveformGridLayout);
            app.DisplayedChannelBSpinnerLabel.Text = 'Displayed Channel B';
            app.DisplayedChannelBSpinner = uispinner(app.WaveformGridLayout);
            app.DisplayedChannelBSpinner.Limits = [0 383];
            app.DisplayedChannelBSpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayedChannelSpinnerValueChanged, true);
            app.DisplayedChannelBSpinner.Tag = '0 1';
            app.PCATab = uitab(app.PlotTypeTabGroup);
            app.PCATab.Title = 'PCA';
            app.PCATab.Tag = 'pcaSpikes';
            app.PCATabGridLayout = uigridlayout(app.PCATab);
            app.PCAAxes = uiaxes(app.PCATabGridLayout);
            xlabel(app.PCAAxes, 'X')
            ylabel(app.PCAAxes, 'Y')
            zlabel(app.PCAAxes, 'Z')
            app.PC1Axes = uiaxes(app.PCATabGridLayout);
            ylabel(app.PC1Axes, 'PC1')
            zlabel(app.PC1Axes, 'Z')
            app.PC2Axes = uiaxes(app.PCATabGridLayout);
            ylabel(app.PC2Axes, 'PC2')
            zlabel(app.PC2Axes, 'Z')
            app.PC3Axes = uiaxes(app.PCATabGridLayout);
            xlabel(app.PC3Axes, 'Time (msec)')
            ylabel(app.PC3Axes, 'PC3')
            zlabel(app.PC3Axes, 'Z')
            app.FiringRateTab = uitab(app.PlotTypeTabGroup);
            app.FiringRateTab.Title = 'Firing Rate';
            app.FiringRateTab.Tag = 'getSpikeFiringRates';
            app.GridLayout3 = uigridlayout(app.FiringRateTab);
            app.FiringRateAxes = uiaxes(app.GridLayout3);
            xlabel(app.FiringRateAxes, 'X')
            ylabel(app.FiringRateAxes, 'Y')
            zlabel(app.FiringRateAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.PanelGridLayout = uigridlayout(app.Panel);
            app.InfoTextArea = uitextarea(app.PanelGridLayout);
            app.FetchParametersTabGroup = uitabgroup(app.PanelGridLayout);
            app.GeneralTab = uitab(app.FetchParametersTabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout10 = uigridlayout(app.GeneralTab);
            app.StreamBackground = uilabel(app.GridLayout10);
            app.StreamBackground.Text = '';
            app.WindowLengthBackground = uilabel(app.GridLayout10);
            app.WindowLengthBackground.Text = '';
            app.StreamDropDownLabel = uilabel(app.GridLayout10);
            app.StreamDropDownLabel.Text = 'Stream';
            app.StreamDropDown = uidropdown(app.GridLayout10);
            app.StreamDropDown.Items = {'Filtered', 'Raw'};
            app.StreamDropDown.ItemsData = [0 1];
            app.StreamDropDown.ValueChangedFcn = createCallbackFcn(app, @StreamDropDownValueChanged, true);
            app.StreamDropDown.Value = 0;
            app.WindowLengthsecLabel = uilabel(app.GridLayout10);
            app.WindowLengthsecLabel.Text = 'Window Length (sec)';
            app.WindowLengthSpinner = uispinner(app.GridLayout10);
            app.WindowLengthSpinner.Step = 0.05;
            app.WindowLengthSpinner.Limits = [0 3];
            app.WindowLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowLengthSpinnerValueChanged, true);
            app.WindowLengthSpinner.Tag = 'window';
            app.WindowLengthSpinner.Value = 1;
            app.FetchFractionBackground = uilabel(app.GridLayout10);
            app.FetchFractionBackground.Text = '';
            app.FractionSpinnerLabel = uilabel(app.GridLayout10);
            app.FractionSpinnerLabel.Text = 'Fraction';
            app.FractionSpinner = uispinner(app.GridLayout10);
            app.FractionSpinner.Step = 0.1;
            app.FractionSpinner.Limits = [0.1 Inf];
            app.FractionSpinner.ValueChangedFcn = createCallbackFcn(app, @FractionSpinnerValueChanged, true);
            app.FractionSpinner.Value = 0.1;
            app.EstimationMethodBackground = uilabel(app.GridLayout10);
            app.EstimationMethodBackground.Text = '';
            app.EstimationMethodDropDownLabel = uilabel(app.GridLayout10);
            app.EstimationMethodDropDownLabel.Text = 'Estimation Method';
            app.EstimationMethodDropDown = uidropdown(app.GridLayout10);
            app.EstimationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @EstimationMethodDropDownValueChanged, true);
            app.ThresholdBackground = uilabel(app.GridLayout10);
            app.ThresholdBackground.Text = '';
            app.StayBelowBackground = uilabel(app.GridLayout10);
            app.StayBelowBackground.Text = '';
            app.ThresholdSpinnerLabel = uilabel(app.GridLayout10);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.GridLayout10);
            app.ThresholdSpinner.Limits = [3 Inf];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Value = 3;
            app.DetectionMethodBackground = uilabel(app.GridLayout10);
            app.DetectionMethodBackground.Text = '';
            app.DetectionMethodDropDownLabel = uilabel(app.GridLayout10);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.GridLayout10);
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.ArtifactFractionBackground = uilabel(app.GridLayout10);
            app.ArtifactFractionBackground.Text = '';
            app.ArtifactFractionLabel = uilabel(app.GridLayout10);
            app.ArtifactFractionLabel.Text = 'Artifact Fraction';
            app.ArtifactFractionSpinner = uispinner(app.GridLayout10);
            app.ArtifactFractionSpinner.Step = 0.1;
            app.ArtifactFractionSpinner.Limits = [0 1];
            app.ArtifactFractionSpinner.ValueChangedFcn = createCallbackFcn(app, @ArtifactFractionSpinnerValueChanged, true);
            app.ArtifactFractionSpinner.Value = 0.2;
            app.StayBelowsamplesLabel = uilabel(app.GridLayout10);
            app.StayBelowsamplesLabel.Text = 'Stay Below (samples)';
            app.StayBelowSpinner = uispinner(app.GridLayout10);
            app.StayBelowSpinner.Limits = [3 Inf];
            app.StayBelowSpinner.ValueChangedFcn = createCallbackFcn(app, @StayBelowSpinnerValueChanged, true);
            app.StayBelowSpinner.Value = 3;
            app.MoreTab = uitab(app.FetchParametersTabGroup);
            app.MoreTab.Title = 'More';
            app.GridLayout12 = uigridlayout(app.MoreTab);
            app.BinSizeBackground = uilabel(app.GridLayout12);
            app.BinSizeBackground.Text = '';
            app.WindowLengthsecLabel_2 = uilabel(app.GridLayout12);
            app.WindowLengthsecLabel_2.Text = 'Bin size (ms)';
            app.BinSizeSpinner = uispinner(app.GridLayout12);
            app.BinSizeSpinner.Step = 10;
            app.BinSizeSpinner.Limits = [10 200];
            app.BinSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @BinSizeSpinnerValueChanged, true);
            app.BinSizeSpinner.Value = 10;
            app.SigmaBackground = uilabel(app.GridLayout12);
            app.SigmaBackground.Text = '';
            app.StayBelowsamplesLabel_2 = uilabel(app.GridLayout12);
            app.StayBelowsamplesLabel_2.Text = 'Sigma (ms)';
            app.SigmaSpinner = uispinner(app.GridLayout12);
            app.SigmaSpinner.Step = 5;
            app.SigmaSpinner.Limits = [5 Inf];
            app.SigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChanged, true);
            app.SigmaSpinner.Value = 10;
            app.GaussianMultiplierBackground = uilabel(app.GridLayout12);
            app.GaussianMultiplierBackground.Text = '';
            app.WindowLengthsecLabel_3 = uilabel(app.GridLayout12);
            app.WindowLengthsecLabel_3.Text = 'Gaussian Window Mult';
            app.GaussianMultiplierSpinner = uispinner(app.GridLayout12);
            app.GaussianMultiplierSpinner.Limits = [1 Inf];
            app.GaussianMultiplierSpinner.ValueChangedFcn = createCallbackFcn(app, @GaussianMultiplierSpinnerValueChanged, true);
            app.GaussianMultiplierSpinner.Value = 1;
            app.ApplySmoothingCheckBox = uicheckbox(app.GridLayout12);
            app.ApplySmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplySmoothingCheckBoxValueChanged, true);
            app.ApplySmoothingCheckBox.Text = 'Apply Smoothing';
            app.FiltersTab = uitab(app.FetchParametersTabGroup);
            app.FiltersTab.Title = 'Filters';
            app.GridLayout9 = uigridlayout(app.FiltersTab);
            app.LowCutoffBackground = uilabel(app.GridLayout9);
            app.LowCutoffBackground.Text = '';
            app.LowCutoffHzSpinnerLabel = uilabel(app.GridLayout9);
            app.LowCutoffHzSpinnerLabel.Text = 'Low Cutoff (Hz)';
            app.LowCutoffSpinner = uispinner(app.GridLayout9);
            app.LowCutoffSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterSpinnerValueChanged, true);
            app.LowCutoffSpinner.Tag = 'fcL';
            app.HighCutoffBackground = uilabel(app.GridLayout9);
            app.HighCutoffBackground.Text = '';
            app.HighCutoffHzSpinnerLabel = uilabel(app.GridLayout9);
            app.HighCutoffHzSpinnerLabel.Text = 'High Cutoff (Hz)';
            app.HighCutoffSpinner = uispinner(app.GridLayout9);
            app.HighCutoffSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterSpinnerValueChanged, true);
            app.HighCutoffSpinner.Tag = 'fcH';
            app.OrderBackground = uilabel(app.GridLayout9);
            app.OrderBackground.Text = '';
            app.OrderSpinnerLabel = uilabel(app.GridLayout9);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.GridLayout9);
            app.OrderSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterSpinnerValueChanged, true);
            app.OrderSpinner.Tag = 'n';
            app.UpdateFilterButton = uibutton(app.GridLayout9, 'push');
            app.UpdateFilterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFilterButtonPushed, true);
            app.UpdateFilterButton.Text = 'Update';
            app.ApplyFilterCheckBox = uicheckbox(app.GridLayout9);
            app.ApplyFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterCheckBoxValueChanged, true);
            app.ApplyFilterCheckBox.Enable = 'off';
            app.ApplyFilterCheckBox.Text = 'Apply Filter';
            app.GridLayout11 = uigridlayout(app.PanelGridLayout);
            app.InitializeButton = uibutton(app.GridLayout11, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.AutoScrollCheckBox = uicheckbox(app.GridLayout11);
            app.AutoScrollCheckBox.Text = 'Auto Scroll';
            app.FetchTabGroup = uitabgroup(app.PanelGridLayout);
            app.ContinuousTab = uitab(app.FetchTabGroup);
            app.ContinuousTab.Title = 'Continuous';
            app.GridLayout7 = uigridlayout(app.ContinuousTab);
            app.FetchButton = uibutton(app.GridLayout7, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.EventTab = uitab(app.FetchTabGroup);
            app.EventTab.Title = 'Event';
            app.GridLayout8 = uigridlayout(app.EventTab);
            app.PrestimLengthBackground = uilabel(app.GridLayout8);
            app.PrestimLengthBackground.Text = '';
            app.PrestimLengthsecSpinnerLabel = uilabel(app.GridLayout8);
            app.PrestimLengthsecSpinnerLabel.Text = 'Prestim Length (sec)';
            app.PrestimLengthSpinner = uispinner(app.GridLayout8);
            app.PrestimLengthSpinner.Step = 0.05;
            app.PrestimLengthSpinner.Limits = [0 1.5];
            app.PrestimLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @EventLengthSpinnerValueChanged, true);
            app.PrestimLengthSpinner.Tag = 'prestim';
            app.StimLengthBackground = uilabel(app.GridLayout8);
            app.StimLengthBackground.Text = '';
            app.StimLengthsecSpinnerLabel = uilabel(app.GridLayout8);
            app.StimLengthsecSpinnerLabel.Text = 'Stim Length (sec)';
            app.StimLengthSpinner = uispinner(app.GridLayout8);
            app.StimLengthSpinner.Step = 0.05;
            app.StimLengthSpinner.Limits = [0 3];
            app.StimLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @EventLengthSpinnerValueChanged, true);
            app.StimLengthSpinner.Tag = 'stim';
            app.StimLengthSpinner.Value = 1;
            app.PoststimLengthBackground = uilabel(app.GridLayout8);
            app.PoststimLengthBackground.Text = '';
            app.PoststimLengthsecLabel_2 = uilabel(app.GridLayout8);
            app.PoststimLengthsecLabel_2.Text = 'Poststim Length (sec)';
            app.PoststimLengthSpinner = uispinner(app.GridLayout8);
            app.PoststimLengthSpinner.Step = 0.05;
            app.PoststimLengthSpinner.Limits = [0 1.5];
            app.PoststimLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @EventLengthSpinnerValueChanged, true);
            app.PoststimLengthSpinner.Tag = 'poststim';
            app.StimulateandFetchButton = uibutton(app.GridLayout8, 'push');
            app.StimulateandFetchButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateandFetchButtonPushed, true);
            app.StimulateandFetchButton.Text = 'Stimulate and Fetch';
            app.AcquisitionTimeEditField = uieditfield(app.PanelGridLayout, 'text');
            app.FetchLamp = uilamp(app.PanelGridLayout);
            app.FetchLamp.Enable = 'off';
            % Show the figure after all components are created
            app.OPGLXUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opglx(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OPGLXUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OPGLXUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPGLXUIFigure)
        end
    end
end